classdef App_AuVeCoDe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIFahrzeugkonzepteUIFigure     matlab.ui.Figure
        RunSimulation                   matlab.ui.control.Button
        FTM_Logo                        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        LongitudinalDynamicPanel        matlab.ui.container.Panel
        DrivingcycleLabel               matlab.ui.control.Label
        input_driving_cycle             matlab.ui.control.DropDown
        TopspeedinkmhLabel              matlab.ui.control.Label
        input_maximal_speed             matlab.ui.control.Spinner
        Minimumaccelerationtimefrom0to100kmhinsLabel  matlab.ui.control.Label
        input_maximal_acceleration      matlab.ui.control.Spinner
        MinrangekmLabel                 matlab.ui.control.Label
        input_range                     matlab.ui.control.Spinner
        CompleteVehiclePanel            matlab.ui.container.Panel
        FreecrashlengthfrontmmLabel     matlab.ui.control.Label
        input_free_crash_length         matlab.ui.control.Spinner
        FreecrashlengthsidemmLabel      matlab.ui.control.Label
        input_free_crash_length_3       matlab.ui.control.Spinner
        FreecrashlengthrearmmLabel      matlab.ui.control.Label
        input_free_crash_length_2       matlab.ui.control.Spinner
        GroundclearanceLabel            matlab.ui.control.Label
        input_ground_clearance          matlab.ui.control.DropDown
        TotaltrunkvolumeLLabel          matlab.ui.control.Label
        input_trunk_volume              matlab.ui.control.Spinner
        TurningdiametermLabel           matlab.ui.control.Label
        input_turning_diameter          matlab.ui.control.Spinner
        input_tire_load                 matlab.ui.control.CheckBox
        settings_conv                   matlab.ui.control.CheckBox
        settings_aut                    matlab.ui.control.CheckBox
        HVACTab                         matlab.ui.container.Tab
        WeatherConditionsPanel          matlab.ui.container.Panel
        ScenarioLabel                   matlab.ui.control.Label
        input_weather_scenario          matlab.ui.control.DropDown
        MassflowratekgminLabel          matlab.ui.control.Label
        input_massflow_rate             matlab.ui.control.Spinner
        HVACcalculationLabel            matlab.ui.control.Label
        HVAC_calc                       matlab.ui.control.CheckBox
        AmbienttemperatureCDropDownLabel  matlab.ui.control.Label
        input_ambient_temp              matlab.ui.control.DropDown
        RecirculationpercentageLabel    matlab.ui.control.Label
        input_percentage_circair        matlab.ui.control.Spinner
        SettemperatureCLabel_2          matlab.ui.control.Label
        input_temperature_set           matlab.ui.control.DropDown
        InteriorTab                     matlab.ui.container.Tab
        InteriorPanel_3                 matlab.ui.container.Panel
        FirstrowLabel_2                 matlab.ui.control.Label
        SecondrowLabel_2                matlab.ui.control.Label
        SeatinglayoutLabel_2            matlab.ui.control.Label
        input_seating_layout            matlab.ui.control.DropDown
        SeatsfirstrowLabel_3            matlab.ui.control.Label
        input_seats_row_1               matlab.ui.control.Spinner
        SeatssecondrowLabel_3           matlab.ui.control.Label
        input_seats_row_2               matlab.ui.control.Spinner
        UpperBodylengthmmLabel          matlab.ui.control.Label
        input_int_upperbody_length_1    matlab.ui.control.Spinner
        BackrestanglemaxdegLabel        matlab.ui.control.Label
        input_int_angle_br_max_1        matlab.ui.control.Spinner
        HeadroommmLabel                 matlab.ui.control.Label
        input_int_headroom_1            matlab.ui.control.Spinner
        SeatdepthmmLabel                matlab.ui.control.Label
        input_int_seat_depth_1          matlab.ui.control.Spinner
        SeatadjustmentxmmLabel          matlab.ui.control.Label
        input_int_seat_adjustment_x_1   matlab.ui.control.Spinner
        SeatheightmmLabel               matlab.ui.control.Label
        input_int_seat_height_1         matlab.ui.control.Spinner
        SeatadjustmentzmmLabel          matlab.ui.control.Label
        input_int_seat_adjustment_z_1   matlab.ui.control.Spinner
        FootlengthmmLabel               matlab.ui.control.Label
        input_int_foot_length_1         matlab.ui.control.Spinner
        UpperBodylengthmmLabel_2        matlab.ui.control.Label
        input_int_upperbody_length_2    matlab.ui.control.Spinner
        BackrestanglemaxdegLabel_2      matlab.ui.control.Label
        input_int_angle_br_max_2        matlab.ui.control.Spinner
        HeadroommmLabel_2               matlab.ui.control.Label
        input_int_headroom_2            matlab.ui.control.Spinner
        SeatdepthmmLabel_2              matlab.ui.control.Label
        input_int_seat_depth_2          matlab.ui.control.Spinner
        SeatadjustmentxmmLabel_2        matlab.ui.control.Label
        input_int_seat_adjustment_x_2   matlab.ui.control.Spinner
        SeatheightmmLabel_2             matlab.ui.control.Label
        input_int_seat_height_2         matlab.ui.control.Spinner
        SeatadjustmentzmmLabel_2        matlab.ui.control.Label
        input_int_seat_adjustment_z_2   matlab.ui.control.Spinner
        FootlengthmmLabel_2             matlab.ui.control.Label
        input_int_foot_length_2         matlab.ui.control.Spinner
        LegoverlapmmLabel_3             matlab.ui.control.Label
        input_int_x_overlap             matlab.ui.control.Spinner
        LegroomadditionalmmLabel        matlab.ui.control.Label
        input_int_legroom_additional_1  matlab.ui.control.Spinner
        LegroomadditionalmmLabel_2      matlab.ui.control.Label
        input_int_legroom_additional_2  matlab.ui.control.Spinner
        NumberofdoorsLabel_3            matlab.ui.control.Label
        input_n_doors                   matlab.ui.control.Spinner
        WalldistancemmLabel_3           matlab.ui.control.Label
        input_int_b2b_walldistance      matlab.ui.control.Spinner
        UseCaseLabel                    matlab.ui.control.Label
        input_int_usecase               matlab.ui.control.DropDown
        BackrestanglemindegLabel        matlab.ui.control.Label
        input_int_angle_br_min_1        matlab.ui.control.Spinner
        BackrestanglemindegLabel_2      matlab.ui.control.Label
        input_int_angle_br_min_2        matlab.ui.control.Spinner
        BackrestwidthmmLabel            matlab.ui.control.Label
        input_int_backrest_width_1      matlab.ui.control.Spinner
        SinglearmrestwidthmmLabel       matlab.ui.control.Label
        input_int_armrest_width_single_1  matlab.ui.control.Spinner
        GapbetweenseatsmmLabel_5        matlab.ui.control.Label
        input_int_seatgap_1             matlab.ui.control.Spinner
        BackrestthicknessmmLabel        matlab.ui.control.Label
        input_int_thickness_br_1        matlab.ui.control.Spinner
        SeatthicknessmmLabel            matlab.ui.control.Label
        input_int_thickness_seat_1      matlab.ui.control.Spinner
        BackrestwidthmmLabel_2          matlab.ui.control.Label
        input_int_backrest_width_2      matlab.ui.control.Spinner
        SinglearmrestwidthmmLabel_2     matlab.ui.control.Label
        input_int_armrest_width_single_2  matlab.ui.control.Spinner
        GapbetweenseatsmmLabel_6        matlab.ui.control.Label
        input_int_seatgap_2             matlab.ui.control.Spinner
        BackrestthicknessmmLabel_2      matlab.ui.control.Label
        input_int_thickness_br_2        matlab.ui.control.Spinner
        SeatthicknessmmLabel_2          matlab.ui.control.Label
        input_int_thickness_seat_2      matlab.ui.control.Spinner
        input_int_thickness_seat_3      matlab.ui.control.Spinner
        BackrestlengthmmLabel_2         matlab.ui.control.Label
        input_int_backrest_length_2     matlab.ui.control.Spinner
        BackrestlengthmmLabel           matlab.ui.control.Label
        input_int_backrest_length_1     matlab.ui.control.Spinner
        input_int_work                  matlab.ui.control.CheckBox
        input_int_sleep                 matlab.ui.control.CheckBox
        DoublearmrestwidthmmLabel       matlab.ui.control.Label
        input_int_armrest_width_double_1  matlab.ui.control.Spinner
        DoublearmrestwidthmmLabel_2     matlab.ui.control.Label
        input_int_armrest_width_double_2  matlab.ui.control.Spinner
        GaptowallmmLabel                matlab.ui.control.Label
        input_int_gaptowall_1           matlab.ui.control.Spinner
        GaptowallmmLabel_2              matlab.ui.control.Label
        input_int_gaptowall_2           matlab.ui.control.Spinner
        input_int_single_ar_outside_2   matlab.ui.control.CheckBox
        input_int_single_ar_between_2   matlab.ui.control.CheckBox
        input_int_double_ar_2           matlab.ui.control.CheckBox
        input_int_single_ar_outside_1   matlab.ui.control.CheckBox
        input_int_single_ar_between_1   matlab.ui.control.CheckBox
        input_int_double_ar_1           matlab.ui.control.CheckBox
        input_H30Opt                    matlab.ui.control.CheckBox
        ExtrasTab                       matlab.ui.container.Tab
        SensorsPanel                    matlab.ui.container.Panel
        NumberofSonarsensorsSpinnerLabel  matlab.ui.control.Label
        input_sonar                     matlab.ui.control.Spinner
        NumberofRadarsensorsSpinnerLabel  matlab.ui.control.Label
        input_radar                     matlab.ui.control.Spinner
        NumberofSurroundCamerasSpinnerLabel  matlab.ui.control.Label
        input_surround_cameras          matlab.ui.control.Spinner
        NumberofFrontCamerasSpinnerLabel  matlab.ui.control.Label
        input_front_cameras             matlab.ui.control.Spinner
        input_c2x                       matlab.ui.control.CheckBox
        input_5g                        matlab.ui.control.CheckBox
        input_onboard_pc                matlab.ui.control.CheckBox
        input_gps_reciever              matlab.ui.control.CheckBox
        input_gps_correction            matlab.ui.control.CheckBox
        NumberofmechLIDARsensorsLabel   matlab.ui.control.Label
        input_mech_lidar                matlab.ui.control.Spinner
        NumberofmemsLIDARsensorsSpinnerLabel  matlab.ui.control.Label
        input_mems_lidar                matlab.ui.control.Spinner
        InteriorPanel_2                 matlab.ui.container.Panel
        NumberofsideairbagsLabel        matlab.ui.control.Label
        input_side_airbags              matlab.ui.control.Spinner
        input_hvac                      matlab.ui.control.CheckBox
        input_seat_warmers              matlab.ui.control.CheckBox
        input_pneumatic_doors           matlab.ui.control.CheckBox
        input_window_lift               matlab.ui.control.CheckBox
        NumberofinteriordisplaysLabel   matlab.ui.control.Label
        input_displays                  matlab.ui.control.Spinner
        input_adjustable_seats          matlab.ui.control.CheckBox
        input_panorama_roof             matlab.ui.control.CheckBox
        input_sliding_roof              matlab.ui.control.CheckBox
        ManufacturingandmaterialsPanel  matlab.ui.container.Panel
        EstimatedassemblytimehLabel     matlab.ui.control.Label
        input_assembly_time             matlab.ui.control.Spinner
        ClosuresmaterialDropDownLabel   matlab.ui.control.Label
        input_closures_material         matlab.ui.control.DropDown
        AluminiumpercentageBIWLabel     matlab.ui.control.Label
        input_biw_alu_perc              matlab.ui.control.Spinner
        DoormaterialLabel               matlab.ui.control.Label
        input_door_material             matlab.ui.control.DropDown
        TailgatematerialLabel           matlab.ui.control.Label
        input_tailgate_material         matlab.ui.control.DropDown
        HoodmaterialLabel               matlab.ui.control.Label
        input_hood_material             matlab.ui.control.DropDown
        FendersmaterialLabel            matlab.ui.control.Label
        input_fenders_material          matlab.ui.control.DropDown
        input_spare_tire                matlab.ui.control.CheckBox
        PowertrainPanel                 matlab.ui.container.Panel
        input_sbw                       matlab.ui.control.CheckBox
        input_emech_steering            matlab.ui.control.CheckBox
        input_emech_brakes              matlab.ui.control.CheckBox
        input_obc                       matlab.ui.control.CheckBox
        AdditionalFeaturesPanel         matlab.ui.container.Panel
        input_keyless_go                matlab.ui.control.CheckBox
        input_acc                       matlab.ui.control.CheckBox
        input_lks                       matlab.ui.control.CheckBox
        input_bsm                       matlab.ui.control.CheckBox
        input_park_assist               matlab.ui.control.CheckBox
        input_night_vision              matlab.ui.control.CheckBox
        input_trunk_assist              matlab.ui.control.CheckBox
        input_phone_connectivity        matlab.ui.control.CheckBox
        HeadlighttechnologyLabel        matlab.ui.control.Label
        input_headlight_tech            matlab.ui.control.DropDown
        BatteryPanel_2                  matlab.ui.container.Panel
        input_batterycooling_in_z       matlab.ui.control.CheckBox
        OptimizationTab                 matlab.ui.container.Tab
        EnginePanel                     matlab.ui.container.Panel
        DrivetypeLabel                  matlab.ui.control.Label
        input_drive_type                matlab.ui.control.DropDown
        EnginetypeLabel                 matlab.ui.control.Label
        input_engine_type               matlab.ui.control.DropDown
        MinengineanglefrontdegLabel     matlab.ui.control.Label
        input_engine_angle_front_min    matlab.ui.control.Spinner
        MaxengineanglefrontdegLabel     matlab.ui.control.Label
        input_engine_angle_front_max    matlab.ui.control.Spinner
        MinengineanglereardegLabel      matlab.ui.control.Label
        input_engine_angle_rear_min     matlab.ui.control.Spinner
        MaxengineanglereardegLabel      matlab.ui.control.Label
        input_engine_angle_rear_max     matlab.ui.control.Spinner
        MintorqueratiofrontLabel        matlab.ui.control.Label
        input_torque_ratio_min          matlab.ui.control.Spinner
        MaxtorqueratiofrontLabel        matlab.ui.control.Label
        input_torque_ratio_max          matlab.ui.control.Spinner
        AxlePanel                       matlab.ui.container.Panel
        MinaxleanglexydegLabel          matlab.ui.control.Label
        input_axle_angle_1_min          matlab.ui.control.Spinner
        MaxaxleanglexydegLabel          matlab.ui.control.Label
        input_axle_angle_1_max          matlab.ui.control.Spinner
        MinaxleanglexzdegLabel          matlab.ui.control.Label
        input_axle_angle_2_min          matlab.ui.control.Spinner
        MaxaxleanglexzdegLabel          matlab.ui.control.Label
        input_axle_angle_2_max          matlab.ui.control.Spinner
        RearaxletypeLabel               matlab.ui.control.Label
        input_rear_axis_type            matlab.ui.control.DropDown
        GearboxPanel                    matlab.ui.container.Panel
        GeartypeRearLabel               matlab.ui.control.Label
        input_gear_type_rear            matlab.ui.control.DropDown
        GeartypeFrontLabel              matlab.ui.control.Label
        input_gear_type_front           matlab.ui.control.DropDown
        Mingearratio1frontLabel         matlab.ui.control.Label
        input_gear_ratio_1_front_min    matlab.ui.control.Spinner
        Maxgearratio1frontLabel         matlab.ui.control.Label
        input_gear_ratio_1_front_max    matlab.ui.control.Spinner
        Mingearratio1rearLabel          matlab.ui.control.Label
        input_gear_ratio_1_rear_min     matlab.ui.control.Spinner
        Maxgearratio1rearLabel          matlab.ui.control.Label
        input_gear_ratio_1_rear_max     matlab.ui.control.Spinner
        GearboxoptgoalLabel             matlab.ui.control.Label
        input_gearbox_opt               matlab.ui.control.DropDown
        OthersPanel                     matlab.ui.container.Panel
        MintirediametermmLabel          matlab.ui.control.Label
        input_tire_diam_min             matlab.ui.control.Spinner
        MaxtirediametermmLabel          matlab.ui.control.Label
        input_tire_diam_max             matlab.ui.control.Spinner
        MinsteeringdistributionfrontLabel  matlab.ui.control.Label
        input_steering_ratio_min        matlab.ui.control.Spinner
        MaxsteeringdistributionfrontLabel  matlab.ui.control.Label
        input_steering_ratio_max        matlab.ui.control.Spinner
        InteriorPanel_4                 matlab.ui.container.Panel
        MinH30frontLabel                matlab.ui.control.Label
        input_minH30_front              matlab.ui.control.Spinner
        MaxH30frontLabel                matlab.ui.control.Label
        input_maxH30_front              matlab.ui.control.Spinner
        MinH30rearLabel                 matlab.ui.control.Label
        input_minH30_rear               matlab.ui.control.Spinner
        MaxH30rearLabel                 matlab.ui.control.Label
        input_maxH30_rear               matlab.ui.control.Spinner
        MininteriorheightmmLabel        matlab.ui.control.Label
        input_int_height_min            matlab.ui.control.Spinner
        MintrunkratiofrontrearLabel     matlab.ui.control.Label
        input_trunk_ratio_min           matlab.ui.control.Spinner
        MaxtrunkratiofrontrearLabel     matlab.ui.control.Label
        input_trunk_ratio_max           matlab.ui.control.Spinner
        MaxinteriorheightmmLabel        matlab.ui.control.Label
        input_int_height_max            matlab.ui.control.Spinner
        BatteryPanel                    matlab.ui.container.Panel
        CelltypeLabel                   matlab.ui.control.Label
        input_cell_type                 matlab.ui.control.DropDown
        SmalloverlapLabel               matlab.ui.control.Label
        input_small_overlap             matlab.ui.control.DropDown
        ExteriorPanel                   matlab.ui.container.Panel
        MinfrontwindowangleLabel        matlab.ui.control.Label
        input_window_angle_front_min    matlab.ui.control.Spinner
        MinrearwindowangleLabel         matlab.ui.control.Label
        input_window_angle_rear_min     matlab.ui.control.Spinner
        FronttrunkDropDownLabel         matlab.ui.control.Label
        input_trunk_type_front          matlab.ui.control.DropDown
        ReartrunkDropDownLabel          matlab.ui.control.Label
        input_trunk_type_rear           matlab.ui.control.DropDown
        MaxfrontwindowangleLabel        matlab.ui.control.Label
        input_window_angle_front_max    matlab.ui.control.Spinner
        MaxrearwindowangleLabel         matlab.ui.control.Label
        input_window_angle_rear_max     matlab.ui.control.Spinner
        input_dist_wheelhouse2bumper_r_min  matlab.ui.control.Spinner
        input_dist_wheelhouse2bumper_r_max  matlab.ui.control.Spinner
        FrontLabel                      matlab.ui.control.Label
        RearLabel                       matlab.ui.control.Label
        MindistancewheelhousebumpermmLabel  matlab.ui.control.Label
        input_dist_wheelhouse2bumper_f_min  matlab.ui.control.Spinner
        MaxdistancewheelhousebumpermmLabel  matlab.ui.control.Label
        input_dist_wheelhouse2bumper_f_max  matlab.ui.control.Spinner
        MindistanceinteriortofrontwagonmmLabel  matlab.ui.control.Label
        input_dist_int2fw_min           matlab.ui.control.Spinner
        FrontLabel_2                    matlab.ui.control.Label
        input_dist_int2rw_min           matlab.ui.control.Spinner
        input_dist_int2rw_max           matlab.ui.control.Spinner
        RearLabel_2                     matlab.ui.control.Label
        MaxdistanceinteriortofrontwagonmmLabel  matlab.ui.control.Label
        input_dist_int2fw_max           matlab.ui.control.Spinner
        ResultsTab                      matlab.ui.container.Tab
        Output_Text_2                   matlab.ui.control.Label
        Output_Label                    matlab.ui.control.Label
        Error_Label                     matlab.ui.control.Label
        SAVECONFIGURATIONButton         matlab.ui.control.Button
        LOADCONFIGURATIONButton         matlab.ui.control.Button
        AutonomousVehicleConceptDevelopmentToolLabel  matlab.ui.control.Label
        CalculationandPlotSettingsPanel  matlab.ui.container.Panel
        VisualizationPanel              matlab.ui.container.Panel
        plot_battery                    matlab.ui.control.CheckBox
        plot_manikin                    matlab.ui.control.CheckBox
        plot_boundary                   matlab.ui.control.CheckBox
        plot_wheel_env                  matlab.ui.control.CheckBox
        OptimizationPanel               matlab.ui.container.Panel
        sing_veh                        matlab.ui.control.CheckBox
        PopulationSizeLabel             matlab.ui.control.Label
        input_pop_size                  matlab.ui.control.Spinner
        MaxgenerationLabel              matlab.ui.control.Label
        input_max_gen                   matlab.ui.control.Spinner
        input_parallel_comp             matlab.ui.control.CheckBox
        TimeframeDropDownLabel          matlab.ui.control.Label
        input_timeframe                 matlab.ui.control.DropDown
        Goal1Label                      matlab.ui.control.Label
        input_goal_1                    matlab.ui.control.DropDown
        Goal2Label                      matlab.ui.control.Label
        input_goal_2                    matlab.ui.control.DropDown
        input_scale_axis                matlab.ui.control.CheckBox
        input_showwarning               matlab.ui.control.CheckBox
        OptimizationAlgorithmPanel      matlab.ui.container.Panel
        SAVEOPTIMIZATIONPARAMETERSButton  matlab.ui.control.Button
        MOPSOCheckBox                   matlab.ui.control.CheckBox
        NSGAIICheckBox                  matlab.ui.control.CheckBox
        MultiswarmMOPSOCheckBox         matlab.ui.control.CheckBox
        MultipopulationNSGAIICheckBox   matlab.ui.control.CheckBox
        NumberofOptimizationSpinnerLabel  matlab.ui.control.Label
        NumberofOptimizationSpinner     matlab.ui.control.Spinner
        Label                           matlab.ui.control.Label
        SaveFolder                      matlab.ui.control.EditField
        SelectOptimizationFolder        matlab.ui.control.Button
        ResetPathButton                 matlab.ui.control.Button
        SaveOptimizationResultsCheckBox  matlab.ui.control.CheckBox
        TUM_logo                        matlab.ui.control.Image
        Output_Text                     matlab.ui.control.Label
        AdrianKnigFTMTUMDissertationProject20182022Label  matlab.ui.control.Label
        SAVESETTINGSFOREXTERNALSIMULATIONButton  matlab.ui.control.Button
        Run_Text                        matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        % global variables by Fabian
        
        SaveFolderName; % variable to store the folder name for optimization results
        
        % MasterFolder specifies the main folder where all optimization
        % results folders are saved. Change this if they are supposed to be
        % saved in other Folder than 06_Results
        MasterFolder = fullfile(fileparts(which('Run_AuVeCoDe')), "06_Results");
        
        % OptimizerIndex specifies a unique integer corresponding with an
        % optimizer
        OptimizerIndex = 0;
        % Current index corresponds with the optimizer selected by user
        CurrentIndex;
        
    end
    
    methods (Access = private)
        function info_msg(app,varargin) % function indicates user that the simulation is running
            app.Run_Text.Text="Simulation running...please wait";
            app.Output_Text.Visible='off'; % Makes output of previous simulation invisible
            app.Output_Label.Visible='off'; % Makes output of previous simulation invisible
            app.Error_Label.Visible='off'; % Makes error-label invisible
            app.Run_Text.Visible='on'; % Makes run-info visible
            
            if nargin>1
                if sum(varargin{1}) == 0
                    % inform user in case no optimizer is selected
                    app.Run_Text.Text="No valid optimizer used";
                    return;
                end
            end
            
        end
        
        function plot_output(app,vehicle) % function plots the output values after single vehicle calculation
            app.Run_Text.Visible='off'; % Makes run-info invisible
            app.Output_Text.Visible='on'; % Makes output box visible
            % Change text of output box to current values
            app.Output_Text.Text=horzcat({strcat('Vehicle empty weight is:',' ',num2str(vehicle.masses.vehicle_empty_weight,'%.2f'),'kg')},{''},...
                {strcat('Vehicle length is:',' ',num2str(vehicle.dimensions.GX.vehicle_length,'%.2f'),'mm')},{''},...
                {strcat('Manufacturing cost of the vehicle is:',' ',num2str(vehicle.Cost.Manufacturing_Cost,'%.2f'),'€')});
            
            % Identify Torque
            if vehicle.LDS.settings.filled_axles(1)
                Torque_front=vehicle.LDS.MOTOR{1, 1}.T_max;
            else
                Torque_front=0;
            end
            
            if vehicle.LDS.settings.filled_axles(2)
                Torque_rear=vehicle.LDS.MOTOR{1, 2}.T_max;
            else
                Torque_rear=0;
            end
            
            app.Output_Text_2.Text=horzcat({strcat('Range_set:',num2str(vehicle.Input.range,'%.2f'),'km')},{''},...
                {strcat('Range_is:',num2str(vehicle.LDS.range,'%.2f'),'km')},{''},...
                {strcat('Acceleration_set:',num2str(vehicle.Input.acceleration_time_req,'%.2f'),'s')},{''},...
                {strcat('Acceleration_is:',num2str(vehicle.LDS.sim_acc.acc_time_is,'%.2f'),'s')},{''},...
                {strcat('Max_speed_set:',num2str(vehicle.Input.max_speed,'%.2f'),'km/h')},{''},...
                {strcat('Max_speed_is:',num2str(vehicle.LDS.sim_speed.max_speed_is,'%.2f'),'km/h')},{''},...
                {strcat('Tire_Radius_set:',num2str(vehicle.Input.r_tire,'%.2f'),'mm')},{''},...
                {strcat('Tire_Radius_is:',num2str(vehicle.LDS.wheel.r_tire,'%.2f'),'mm')},{''},...
                {strcat('Tire_Width_is:',num2str(vehicle.dimensions.CY.wheel_f_width,'%.2f'),'mm')},{''},...
                {strcat('Rim_Diameter_is:',num2str(vehicle.dimensions.CX.rim_f_diameter,'%.2f'),'inch')},{''},...
                {strcat('Battery_cap_is:',num2str(vehicle.battery.energy_is_gross_in_kWh,'%.2f'),'kWh')},{''},...
                {strcat('Torque_Front_is:',num2str(Torque_front,'%.2f'),'Nm')},{''},...
                {strcat('Torque_Rear_is:',num2str(Torque_rear,'%.2f'),'Nm')},{''},...
                {strcat('Length_is:',num2str(vehicle.dimensions.GX.vehicle_length,'%.2f'),'mm')},{''},...
                {strcat('Width_is:',num2str(vehicle.dimensions.GY.vehicle_width,'%.2f'),'mm')},{''},...
                {strcat('Height_is:',num2str(vehicle.dimensions.GZ.vehicle_height,'%.2f'),'mm')},{''});
            
            
        end
        
        function limit_backrestangle(app)
            if app.input_int_angle_br_min_1.Value>app.input_int_angle_br_max_1.Value
            app.input_int_angle_br_min_1.Value= app.input_int_angle_br_max_1.Value;
            end
            if app.input_int_angle_br_min_2.Value>app.input_int_angle_br_max_2.Value
            app.input_int_angle_br_min_2.Value= app.input_int_angle_br_max_2.Value;
            end            
        end
        
        function limit_backrestthickness(app)
            app.input_int_thickness_br_1.Limits(2) =  350;
            app.input_int_thickness_br_2.Limits(2) =  350;
            app.input_int_thickness_br_1.Limits = [app.input_int_thickness_br_1.Limits(1),max(app.input_int_thickness_br_1.Limits(1), app.input_int_thickness_seat_1.Value)];
            app.input_int_thickness_br_2.Limits = [app.input_int_thickness_br_2.Limits(1),max(app.input_int_thickness_br_2.Limits(1), app.input_int_thickness_seat_2.Value)];
        end
        
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
        
        function taskpackage(app)
            
            % work
            if app.input_int_work.Value == true
               app.input_int_angle_br_max_1.Value = 90;
               app.input_int_angle_br_max_2.Value = 90;
            end            
            
            %sleep
            if app.input_int_sleep.Value == true
               app.input_int_angle_br_min_1.Value = 0;
               app.input_int_angle_br_min_2.Value = 0;
               app.input_int_legroom_additional_1.Value = 500;
               app.input_int_legroom_additional_2.Value = 500;
            end
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function  armrest(app)
            % Deactivate Double armrest if single armrest in between is on
            % and other way round
            if app.input_seats_row_1.Value > 1
                if app.input_int_single_ar_between_1.Value == true
                   app.input_int_double_ar_1.Value = 0;
                   app.input_int_double_ar_1.Enable = 'off';
                end       
                
                if app.input_int_single_ar_between_1.Value == false
                   app.input_int_double_ar_1.Enable = 'on';
                end 
                
                if app.input_int_double_ar_1.Value == true
                   app.input_int_single_ar_between_1.Value = 0;
                   app.input_int_single_ar_between_1.Enable = 'off';
                end       
                
                if app.input_int_double_ar_1.Value == false
                   app.input_int_single_ar_between_1.Enable = 'on';
                end 
            end
            %%
            if app.input_seats_row_2.Value > 1
                if app.input_int_single_ar_between_2.Value == true
                   app.input_int_double_ar_2.Value = 0;
                   app.input_int_double_ar_2.Enable = 'off';
                end       
                
                if app.input_int_single_ar_between_2.Value == false
                   app.input_int_double_ar_2.Enable = 'on';
                end 
                
                if app.input_int_double_ar_2.Value == true
                   app.input_int_single_ar_between_2.Value = 0;
                   app.input_int_single_ar_between_2.Enable = 'off';
                end       
                
                if app.input_int_double_ar_2.Value == false
                   app.input_int_single_ar_between_2.Enable = 'on';
                end 
            end
        end
        function UseCase(app)
            value = app.input_int_usecase.Value;
            
            if strcmp(value,'Individual')
                %app.input_int_upperbody_length_1.Value = 888;                
            elseif  strcmp(value,'Comfort Class')
                app.input_int_b2b_walldistance.Value = 50;
                app.input_int_x_overlap.Value = 0;
                app.input_int_upperbody_length_1.Value = 976;
                app.input_int_upperbody_length_2.Value = 976;
                app.input_int_angle_br_max_1.Value = 80;
                app.input_int_angle_br_max_2.Value = 80;
                app.input_int_angle_br_min_1.Value = 50;
                app.input_int_angle_br_min_2.Value = 50;
                app.input_int_headroom_1.Value = 150;
                app.input_int_headroom_2.Value = 150;
                app.input_int_seat_depth_1.Value = 460;
                app.input_int_seat_depth_2.Value = 460;
                app.input_int_seat_adjustment_x_1.Value = 116;
                app.input_int_seat_adjustment_x_2.Value = 116;
                app.input_int_seat_height_1.Value = 500;
                app.input_int_seat_height_2.Value = 500;
                app.input_int_seat_adjustment_z_1.Value = 100;
                app.input_int_seat_adjustment_z_2.Value = 100;
                app.input_int_foot_length_1.Value = 310;
                app.input_int_foot_length_2.Value = 310;
                app.input_int_legroom_additional_1.Value = 200;
                app.input_int_legroom_additional_2.Value = 200;
                app.input_int_backrest_width_1.Value = 570;
                app.input_int_backrest_width_2.Value = 570;
                app.input_int_thickness_seat_1.Value = 130;
                app.input_int_thickness_seat_2.Value = 130;
                limit_backrestthickness(app)
                app.input_int_thickness_br_1.Value = 120;
                app.input_int_thickness_br_2.Value = 120;
                
                app.input_int_backrest_length_1.Value = 980;
                app.input_int_backrest_length_2.Value = 980;
                
                app.input_int_armrest_width_single_1.Value = 74;
                app.input_int_armrest_width_single_2.Value = 74;
                app.input_int_armrest_width_double_1.Value = 246;
                app.input_int_armrest_width_double_2.Value = 246;
                app.input_int_gaptowall_1.Value = 58;
                app.input_int_gaptowall_2.Value = 58;
                app.input_int_seatgap_1.Value = 0;
                app.input_int_seatgap_2.Value = 0;
                app.input_int_double_ar_1.Value = true;
                app.input_int_double_ar_2.Value = true;
                app.input_int_single_ar_outside_1.Value =true;
                app.input_int_single_ar_outside_2.Value =true;
                app.input_int_single_ar_between_1.Value = false;
                app.input_int_single_ar_between_2.Value = false;
                armrest(app);
                if strcmp(app.input_seating_layout.Value,'Single Row')
                    app.input_int_single_ar_outside_2.Enable = 'off';
                    app.input_int_single_ar_between_2.Enable = 'off';
                    app.input_int_double_ar_2.Enable = 'off';
                end
                
            elseif  strcmp(value,'Middle Class')
                app.input_int_b2b_walldistance.Value = 30;
                app.input_int_x_overlap.Value = 50;
                app.input_int_upperbody_length_1.Value = 976;
                app.input_int_upperbody_length_2.Value = 976;
                app.input_int_angle_br_max_1.Value = 80;
                app.input_int_angle_br_max_2.Value = 80;
                limit_backrestangle(app)
                
                app.input_int_angle_br_min_1.Value = 50;
                app.input_int_angle_br_min_2.Value = 50;
                app.input_int_headroom_1.Value = 60;
                app.input_int_headroom_2.Value = 60;
                app.input_int_seat_depth_1.Value = 460;
                app.input_int_seat_depth_2.Value = 460;
                app.input_int_seat_adjustment_x_1.Value = 50;
                app.input_int_seat_adjustment_x_2.Value = 50;
                app.input_int_seat_height_1.Value = 500;
                app.input_int_seat_height_2.Value = 500;
                app.input_int_seat_adjustment_z_1.Value = 50;
                app.input_int_seat_adjustment_z_2.Value = 50;
                app.input_int_foot_length_1.Value = 310;
                app.input_int_foot_length_2.Value = 310;
                app.input_int_legroom_additional_1.Value = 100;
                app.input_int_legroom_additional_2.Value = 100;
                app.input_int_backrest_width_1.Value = 540;
                app.input_int_backrest_width_2.Value = 540;
                app.input_int_thickness_seat_1.Value = 115;
                app.input_int_thickness_seat_2.Value = 115;
                
                limit_backrestthickness(app)
                
                app.input_int_thickness_br_1.Value = 100;
                app.input_int_thickness_br_2.Value = 100;
                app.input_int_backrest_length_1.Value = 850;
                app.input_int_backrest_length_2.Value = 850;
                
                app.input_int_armrest_width_single_1.Value = 33;
                app.input_int_armrest_width_single_2.Value = 33;
                app.input_int_armrest_width_double_1.Value = 170;
                app.input_int_armrest_width_double_2.Value = 170;
                app.input_int_gaptowall_1.Value = 81;
                app.input_int_gaptowall_2.Value = 81;
                app.input_int_seatgap_1.Value = 26;
                app.input_int_seatgap_2.Value = 26;
                app.input_int_double_ar_1.Value = false;
                app.input_int_double_ar_2.Value = false;
                app.input_int_single_ar_outside_1.Value =true;
                app.input_int_single_ar_outside_2.Value =true;
                app.input_int_single_ar_between_1.Value = false;
                app.input_int_single_ar_between_2.Value = false;
                armrest(app)
                if strcmp(app.input_seating_layout.Value,'Single Row')
                    app.input_int_single_ar_outside_2.Enable = 'off';
                    app.input_int_single_ar_between_2.Enable = 'off';
                    app.input_int_double_ar_2.Enable = 'off';
                end
                
            else    % Low Class
                app.input_int_b2b_walldistance.Value = 0;
                app.input_int_x_overlap.Value = 200;
                app.input_int_upperbody_length_1.Value = 976;
                app.input_int_upperbody_length_2.Value = 976;
                app.input_int_angle_br_max_1.Value = 75;
                app.input_int_angle_br_max_2.Value = 75;
                limit_backrestangle(app)
                app.input_int_angle_br_min_1.Value = 75;
                app.input_int_angle_br_min_2.Value = 75;
                app.input_int_headroom_1.Value = 0;
                app.input_int_headroom_2.Value = 0;
                app.input_int_seat_depth_1.Value = 420;
                app.input_int_seat_depth_2.Value = 420;
                app.input_int_seat_adjustment_x_1.Value = 0;
                app.input_int_seat_adjustment_x_2.Value = 0;
                app.input_int_seat_height_1.Value = 455;
                app.input_int_seat_height_2.Value = 455;
                app.input_int_seat_adjustment_z_1.Value = 0;
                app.input_int_seat_adjustment_z_2.Value = 0;
                app.input_int_foot_length_1.Value = 310;
                app.input_int_foot_length_2.Value = 310;
                app.input_int_legroom_additional_1.Value = 0;
                app.input_int_legroom_additional_2.Value = 0;
                app.input_int_backrest_width_1.Value = 420;
                app.input_int_backrest_width_2.Value = 420;
                app.input_int_thickness_seat_1.Value = 30;
                app.input_int_thickness_seat_2.Value = 30;
                
                limit_backrestthickness(app)
                
                app.input_int_thickness_br_1.Value = 30;
                app.input_int_thickness_br_2.Value = 30;
                app.input_int_backrest_length_1.Value = 550;
                app.input_int_backrest_length_2.Value = 550;
                
                app.input_int_armrest_width_single_1.Value = 0;
                app.input_int_armrest_width_single_2.Value = 0;
                app.input_int_armrest_width_double_1.Value = 0;
                app.input_int_armrest_width_double_2.Value = 0;
                app.input_int_gaptowall_1.Value = 0;
                app.input_int_gaptowall_2.Value = 0;
                app.input_int_seatgap_1.Value = 20;
                app.input_int_seatgap_2.Value = 20;
                app.input_int_double_ar_1.Value = false;
                app.input_int_double_ar_2.Value = false;
                app.input_int_single_ar_outside_1.Value =false;
                app.input_int_single_ar_outside_2.Value =false;
                app.input_int_single_ar_between_1.Value = false;
                app.input_int_single_ar_between_2.Value = false;
                armrest(app)
                if strcmp(app.input_seating_layout.Value,'Single Row')
                    app.input_int_single_ar_outside_2.Enable = 'off';
                    app.input_int_single_ar_between_2.Enable = 'off';
                    app.input_int_double_ar_2.Enable = 'off';
                end
                
                limit_backrestthickness(app);
            end
            if app.input_int_work.Value == true || app.input_int_sleep.Value == true
                taskpackage(app);
            end
        end
        
        function seatheight(app)
            if (app.input_int_seat_height_1.Value - app.input_int_seat_adjustment_z_1.Value - app.input_int_thickness_seat_1.Value) < 0
                app.input_int_seat_height_1.Value = app.input_int_seat_adjustment_z_1.Value + app.input_int_thickness_seat_1.Value;
                f = errordlg('Seat height not feasible','Seat height Error');
            end
            
            if (app.input_int_seat_height_2.Value - app.input_int_seat_adjustment_z_2.Value - app.input_int_thickness_seat_2.Value) < 0
                app.input_int_seat_height_2.Value = app.input_int_seat_adjustment_z_2.Value + app.input_int_thickness_seat_2.Value;
                f = errordlg('Seat height not feasible','Seat height Error');
            end
        end
        
        function H30_Optimization(app)
            if app.input_H30Opt.Value == true
                app.input_maxH30_front.Enable = 'on';
                app.input_maxH30_rear.Enable ='on';
                app.input_minH30_front.Enable = 'on';
                app.input_minH30_rear.Enable ='on';
                %Disable input at interior site
                app.input_int_seat_adjustment_z_1.Enable = 'off';
                app.input_int_seat_adjustment_z_2.Enable = 'off';
                app.input_int_seat_height_1.Enable  = 'off';
                app.input_int_seat_height_2.Enable = 'off';
            else
                app.input_maxH30_front.Enable = 'off';
                app.input_maxH30_rear.Enable ='off';
                app.input_minH30_front.Enable = 'off';
                app.input_minH30_rear.Enable ='off';
                %Activate input at interior site
                app.input_int_seat_adjustment_z_1.Enable = 'on';
                app.input_int_seat_adjustment_z_2.Enable = 'on';                
                app.input_int_seat_height_1.Enable  = 'on';
                app.input_int_seat_height_2.Enable = 'on';
                UseCase(app)
            end
        end
        
        function H30_Check(app)
               %Check inputs (max. has to be more than min.)
               if app.input_maxH30_front.Value < app.input_minH30_front.Value
                   app.input_maxH30_front.Value=app.input_minH30_front.Value;
               end
               if app.input_maxH30_rear.Value < app.input_minH30_rear.Value
                   app.input_maxH30_rear.Value=app.input_minH30_rear.Value;
               end
               %Check if seat thickness is smaller then min. H30
               if app.input_int_thickness_seat_1.Value>app.input_minH30_front.Value
                   app.input_minH30_front.Value=app.input_int_thickness_seat_1.Value;
               end
               if app.input_int_thickness_seat_2.Value>app.input_minH30_rear.Value
                   app.input_minH30_rear.Value=app.input_int_thickness_seat_2.Value;
               end
        end
    
        function UpdateOptimizerPanel(app)
            
            % Function by Fabian
            % This functions updates the checkboxes in optimizer panel
            
            % enable all checkboxes by default
            app.NSGAIICheckBox.Enable = true;
            app.MultipopulationNSGAIICheckBox.Enable = true;
            app.MultiswarmMOPSOCheckBox.Enable = true;
            app.MOPSOCheckBox.Enable = true;
            
            % if user already selected an optimizer then disable checkboxes
            % for another optimizer
            
            % MOPSO
            if app.MOPSOCheckBox.Value
                app.NSGAIICheckBox.Enable = false;
                app.MultipopulationNSGAIICheckBox.Enable = false;
                app.MultiswarmMOPSOCheckBox.Enable = false;
            end
            
            % NSGA-II
            if app.NSGAIICheckBox.Value
                app.MOPSOCheckBox.Enable = false;
                app.MultipopulationNSGAIICheckBox.Enable = false;
                app.MultiswarmMOPSOCheckBox.Enable = false;
            end
            
            % Multipopulation NSGA-II
            if app.MultipopulationNSGAIICheckBox.Value
                app.NSGAIICheckBox.Enable = false;
                app.MOPSOCheckBox.Enable = false;
                app.MultiswarmMOPSOCheckBox.Enable = false;
            end
            
            % Multiswarm MOPSO
            if app.MultiswarmMOPSOCheckBox.Value
                app.NSGAIICheckBox.Enable = false;
                app.MultipopulationNSGAIICheckBox.Enable = false;
                app.MOPSOCheckBox.Enable = false;
            end
        end
        
        function GetLastDefaultFolders(app)
            % Function by Fabian
            % This function recognizes the latest available default folders
            % for storing optimization results in MasterFolder
            
            % DEFAULT OPTIMIZATION RESULT FOLDER NAMES:
            % Optimization*unique_number*_*mmddyyyy*
            % Examples:
            % Optimization1_05222020
            % Optimization2_06212020
            % Optimization3_04212021
            
            % Word "Optimization" may be changed per default. This can be
            % changed by changing corresponding words in this functions
            % which are marked by R* in comment.
            
            % enable SaveFolder textbox
            app.SaveFolder.Enable = true;
            
            % get all files in MasterFolder and store in table
            FolderNames = struct2table(dir(app.MasterFolder));
            % look only for all folders
            FolderNames = FolderNames(FolderNames.isdir, :);
            
            % get all folder names containing default folder name. R*
            FolderNames = FolderNames(contains(FolderNames.name, "Optimization"), :);
            % get the unique number after the default folder name and
            % before the character "_". R*
            FindPathName = double(string(cell2mat(extractBetween(FolderNames.name, "Optimization", "_"))));
            % get the latest unique number used in MasterFolder
            FindPathName = max(FindPathName);
            
            % check if the latest unique number can be found
            if isempty(FindPathName)
                % if there are no unique number after processing can be
                % found then set the first default folder name as the
                % current default folder name.
                ShortFolderName = "Optimization01_" + string(datestr(now,"mmddyyyy"));
            else
                % if there are latest unique number can be found then
                % assign the next unique number to current default folder
                % name
                ShortFolderName = "Optimization" + sprintf('%02d',FindPathName + 1) + "_" + string(datestr(now,"mmddyyyy"));
            end
            % build the full path to store optimization result in variable
            % SaveFolderName
            app.SaveFolderName = fullfile(app.MasterFolder, ShortFolderName);
            % show the short folder name in SaveFolder textbox to allow
            % user changes the folder name where the optimization result is
            % going to be saved
            app.SaveFolder.Value = ShortFolderName;
        end
        
    end
    
    %Call function outside userform
    methods (Access = public)
        
        function app = Set_vehicle_settings(app,sett_aut,sett_conv)
            app=Vehicle_settings_matlab(app,sett_aut,sett_conv);
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            limit_backrestthickness(app);
            H30_Optimization(app);
            % initialize default folder name for optimization results
            GetLastDefaultFolders(app); % edit by Fabian
            UpdateOptimizerPanel(app)
        end

        % Button pushed function: RunSimulation
        function StartSimulation(app, event)
            % Button starts Simulation with current input
            app.Lamp.Color= [1.00,1.00,0.00];    
            
            if  app.sing_veh.Value==1 % Single vehicle is selected
                info_msg(app); % indicate that the sim is running
                pause(0.1); % pause required to update the GUI
                Parameters=ConvertUserform(app); %starts conversion of app to Parameter struct
                [vehicle,~]=Startfile_AuVeCoDe(Parameters,app); %STARTS SIMULATION
                pause(0.1); % pause required to update the GUI
                
                if vehicle.feasible % plots the vehicle result if vehicle result if feasible
                    plot_output(app,vehicle);
                end
                app.Lamp.Color= [0.00,1.00,0.00];
                
            else % optimization is selectedtry
                % run optimization
                for i = 1:app.NumberofOptimizationSpinner.Value % edited by Fabian
                    % OptimizationVector to define which optimizer is
                    % selected by the user
                    OptimizationVector = [app.NSGAIICheckBox.Value, app.MOPSOCheckBox.Value,...
                        app.MultipopulationNSGAIICheckBox.Value, app.MultiswarmMOPSOCheckBox.Value];
                    
                    
                    info_msg(app,OptimizationVector) % indicate that the sim is running
                    pause(0.1); % pause required to update the GUI                    
                    
                    %% edited by Fabian
                    % check if user selected an optimizer a
                    if sum(OptimizationVector) == 0
                        % inform user in case no optimizer is selected
                        disp("No valid optimizer used");
                        return;
                    else
                        % find the index of the chosen optimizer for
                        % Startfile if a selected optimizer is found
                        app.OptimizerIndex = find(OptimizationVector == 1);
                        % starts conversion of app to Parameter struct
                        Parameters=ConvertUserform(app);
                    end
                    
                    %% edited by Fabian
                    % save the current index for repeated optimization
                    app.CurrentIndex = i;
                    
                    [vehicle, options]=Startfile_AuVeCoDe(Parameters,app); %STARTS SIMULATION
                    pause(0.1); % pause required to update the GUI
                    load(fullfile(options.AdditionalResultPath,"vehicle_result_green.mat"));
                    plot_output(app,vehicle_green);
                end
                app.Lamp.Color= [0.00,1.00,0.00];


                % automatically refresh the default folder name for
                % optimization result
                GetLastDefaultFolders(app); % edited by Fabian
            end
            
        end

        % Button pushed function: FTM_Logo
        function FTM_LogoPushed(app, event)
            % Opens the website of the Institute of Automotive Technology at TUM
            url = 'https://www.mos.ed.tum.de/en/ftm/home/';
            web(url, '-browser')
        end

        % Callback function
        function InformationButtonPushed(app, event)
            %             imshow('factors.PNG');
            [im, map, alpha] = imread('factors_new.PNG');
            % Source [1]:https://www.google.de/search?biw=1829&bih=890&tbm=isch&sxsrf=ACYBGNRXbJsum3XGhZP2vHYqlrfnZlGA-w%3A1577546941108&sa=1&ei=vXQHXs6eBoPbwAKouYaoAw&q=fahrzeug+seitenansicht+transparent+audi+a6+limousine&oq=fahrzeug+seitenansicht+transparent+audi+a6+limousine&gs_l=img.3...10859.13082..13284...1.0..0.86.926.13......0....1..gws-wiz-img.E12BdVceGcw&ved=0ahUKEwiO8amo1NjmAhWDLVAKHaicATUQ4dUDCAY&uact=5#imgrc=Se-WWcGQu3-kWM:
            % Last call: 28.12.2019
            figure('name','Vehicle geometry factors','NumberTitle','off');
            f = imshow(im);
            set(f, 'AlphaData', alpha);
        end

        % Value changed function: sing_veh
        function sing_vehValueChanged(app, event)
            % function sets default values for single vehicle and
            % optimization
            value = app.sing_veh.Value;
            if value==1
                % Hide Optimization Panel
                app.OptimizationAlgorithmPanel.Visible = 'off';
                
                % Reduce All Combinations Items
                app.input_gear_type_rear.Items = {'Coaxial','Coaxial-layshaft', 'Parallel'};
                app.input_gear_type_front.Items = {'Coaxial','Coaxial-layshaft', 'Parallel'};
                app.input_drive_type.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M'};
                app.input_engine_type.Items = {'ASM', 'PSM'};
                app.input_small_overlap.Items = {'Yes', 'No'};
                app.input_cell_type.Items = {'cylindrical', 'pouch', 'prismatic'};
                app.input_gearbox_opt.Items = {'Length', 'Height'};
                app.input_rear_axis_type.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link'};
                app.input_trunk_type_front.Items = {'integrated','hooded'};
                app.input_trunk_type_rear.Items = {'integrated','hooded'};
                                
                % Optimization Setting Panel Invisible
                app.MaxgenerationLabel.Visible='off';
                app.PopulationSizeLabel.Visible='off';
                app.input_parallel_comp.Visible='off';
                app.input_pop_size.Visible='off';
                app.input_max_gen.Visible='off';
                app.input_goal_1.Visible='off';
                app.Goal1Label.Visible='off';
                app.input_goal_2.Visible='off';
                app.Goal2Label.Visible='off';
                app.input_scale_axis.Visible='off';                
                
                
                % Set default Values
                app.input_drive_type.Value='GM_X';
                app.input_engine_type.Value='PSM';
                app.input_rear_axis_type.Value='torsion_beam';
                app.input_cell_type.Value='pouch';
                app.input_gear_type_front.Value='Parallel';
                app.input_gearbox_opt.Value='Length';
                app.input_small_overlap.Value='No';
                app.input_trunk_ratio_min.Value=50;
                app.input_int_height_min.Value=450;
                app.input_gear_ratio_1_front_min.Value=10;
                app.input_gear_ratio_1_front_max.Value=10;
                app.input_gear_ratio_1_rear_min.Value=10;
                app.input_gear_ratio_1_rear_min.Value=10;
                app.input_engine_angle_front_min.Value=0;
                app.input_engine_angle_rear_min.Value=0;
                app.input_axle_angle_1_min.Value=7;
                app.input_axle_angle_2_min.Value=7;
                app.input_tire_diam_min.Value=700;
                app.input_dist_wheelhouse2bumper_f_min.Value=200;
                app.input_dist_wheelhouse2bumper_r_min.Value=200;
                app.input_dist_int2fw_min.Value=50;
                app.input_dist_int2rw_min.Value=50;                
                app.input_steering_ratio_min.Value=70;
                app.input_torque_ratio_min.Value=50;
                
                if app.input_trunk_volume.Value>0
                    app.input_trunk_ratio_min.Visible='on';
                else
                    app.input_trunk_ratio_min.Visible='off';
                end
                
                % Make Max values invisible
                app.input_trunk_ratio_max.Visible='off';
                app.input_gear_ratio_1_rear_max.Visible='off';
                app.input_gear_ratio_1_front_max.Visible='off';
                app.input_engine_angle_front_max.Visible='off';
                app.input_engine_angle_rear_max.Visible='off';
                app.input_engine_angle_front_min.Visible='on';
                app.input_engine_angle_rear_min.Visible='on';
                app.input_axle_angle_1_max.Visible='off';
                app.input_axle_angle_2_max.Visible='off';
                app.input_tire_diam_max.Visible='off';
                app.input_int_height_max.Visible='off';
                app.input_dist_wheelhouse2bumper_f_max.Visible='off';
                app.input_dist_wheelhouse2bumper_r_max.Visible='off';
                app.input_dist_int2fw_max.Visible='off';
                app.input_dist_int2rw_max.Visible='off';
                app.input_steering_ratio_max.Visible='off';
                app.input_engine_angle_rear_max.Visible='off';
                app.input_torque_ratio_min.Visible='off';
                app.input_torque_ratio_max.Visible='off';
                app.input_maxH30_front.Visible='off';
                app.input_maxH30_rear.Visible='off';
                app.input_window_angle_front_max.Visible='off';
                app.input_window_angle_rear_max.Visible='off';


                % Make Rear axle invisible
                app.input_gear_ratio_1_rear_min.Value=6;
                app.input_gear_ratio_1_rear_max.Value=10;
                app.input_gear_ratio_1_rear_min.Visible='off';
                app.input_gear_ratio_1_rear_max.Visible='off';
                app.Mingearratio1rearLabel.Visible='off';
                app.Maxgearratio1rearLabel.Visible='off';
                
                app.input_engine_angle_rear_min.Value=6;
                app.input_engine_angle_rear_max.Value=10;
                app.input_engine_angle_rear_min.Visible='off';
                app.input_engine_angle_rear_max.Visible='off';
                app.MinengineanglereardegLabel.Visible='off';
                app.MaxengineanglereardegLabel.Visible='off';
                
                app.GeartypeRearLabel.Visible='off';
                app.input_gear_type_rear.Visible='off';
                app.GeartypeFrontLabel.Visible='on';
                app.input_gear_type_front.Visible='on';
                
                % Change Min Labels and make max lables invisible
                app.MintirediametermmLabel.Text='Tire diameter [mm]';
                app.MaxtirediametermmLabel.Visible='off';
                
                app.MininteriorheightmmLabel.Text='Interior height [mm]';
                app.MaxinteriorheightmmLabel.Visible='off';
                
                app.MinaxleanglexydegLabel.Text='Axle angle (xy) [deg]';
                app.MaxaxleanglexydegLabel.Visible='off';
                
                app.MinaxleanglexzdegLabel.Text='Axle angle (xz) [deg]';
                app.MaxaxleanglexzdegLabel.Visible='off';
                
                app.Mingearratio1frontLabel.Text='Gear ratio 1 front';
                app.Mingearratio1frontLabel.Visible='on';
                app.Maxgearratio1frontLabel.Visible='off';                
                              
                app.Mingearratio1rearLabel.Text='Gear ratio 1 rear';
                app.Mingearratio1rearLabel.Visible='off';
                app.Maxgearratio1rearLabel.Visible='off';                
                
                app.MintrunkratiofrontrearLabel.Text='Trunk ratio front [%]';
                if app.input_trunk_volume.Value>0
                    app.MintrunkratiofrontrearLabel.Visible='on';
                    app.MaxtrunkratiofrontrearLabel.Visible='off';
                    app.input_trunk_ratio_max.Visible='off';
                    app.input_trunk_ratio_min.Visible='on';
                else
                    app.MintrunkratiofrontrearLabel.Visible='off';
                    app.MaxtrunkratiofrontrearLabel.Visible='off';
                    app.input_trunk_ratio_max.Visible='off';
                    app.input_trunk_ratio_min.Visible='off';
                end
                
                app.MinengineanglereardegLabel.Text='Engine angle rear [deg]';
                app.MinengineanglereardegLabel.Visible='off';
                app.MaxengineanglereardegLabel.Visible='off';
                
                app.MinengineanglefrontdegLabel.Text='Engine angle front [deg]';
                app.MaxengineanglefrontdegLabel.Visible='off';
                
                
                app.MindistancewheelhousebumpermmLabel.Text='Distance wheelhouse-bumper [mm]';
                app.MaxdistancewheelhousebumpermmLabel.Visible='off';
                
                app.MindistanceinteriortofrontwagonmmLabel.Text='Distance interior to front wagon [mm]';
                app.MaxdistanceinteriortofrontwagonmmLabel.Visible='off';
                
                app.MinsteeringdistributionfrontLabel.Text='Steering distribution front [%]';
                app.MaxsteeringdistributionfrontLabel.Visible='off';
                
                app.MintorqueratiofrontLabel.Text='Torque ratio front [%]';
                app.MintorqueratiofrontLabel.Visible='off';
                app.MaxtorqueratiofrontLabel.Visible='off';
                
                app.MaxH30frontLabel.Visible  = 'off';
                app.MaxH30rearLabel.Visible  = 'off';
                app.MinH30frontLabel.Text  = 'H30 front';
                app.MinH30rearLabel.Text  = 'H30 rear';
                
                app.MaxfrontwindowangleLabel.Visible = 'off';
                app.MaxrearwindowangleLabel.Visible = 'off';
                app.MinfrontwindowangleLabel.Text = 'Front window angle [°]';
                app.MinrearwindowangleLabel.Text = 'Rear window angle [°]';

                
            elseif value==0
                %Show optimization panel
                app.OptimizationAlgorithmPanel.Visible = 'on';
                
                % Expand All Combinations Items
                app.input_gear_type_rear.Items = {'Coaxial','Coaxial-layshaft', 'Parallel', 'All Combinations'};
                app.input_gear_type_front.Items = {'Coaxial','Coaxial-layshaft', 'Parallel', 'All Combinations'};
                app.input_drive_type.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
                app.input_engine_type.Items = {'ASM', 'PSM', 'All Combinations'};
                app.input_small_overlap.Items = {'Yes', 'No', 'All Combinations'};
                app.input_cell_type.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
                app.input_gearbox_opt.Items = {'Length', 'Height', 'All Combinations'};
                app.input_rear_axis_type.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
                app.input_trunk_type_front.Items = {'integrated','hooded','All Combinations'};
                app.input_trunk_type_rear.Items = {'integrated','hooded','All Combinations'};
                                
                % Optimization Setting Panel visible
                app.MaxgenerationLabel.Visible='on';
                app.PopulationSizeLabel.Visible='on';
                app.input_parallel_comp.Visible='on';
                app.input_pop_size.Visible='on';
                app.input_max_gen.Visible='on';
                app.input_goal_1.Visible='on';
                app.Goal1Label.Visible='on';
                app.input_goal_2.Visible='on';
                app.Goal2Label.Visible='on';
                app.input_scale_axis.Visible='on';
                
                
                % Set default Values
                app.input_drive_type.Value='All Combinations';
                app.input_engine_type.Value='All Combinations';
                app.input_rear_axis_type.Value='All Combinations';
                app.input_cell_type.Value='All Combinations';
                app.input_gear_type_front.Value='All Combinations';
                app.input_gear_type_rear.Value='All Combinations';
                app.input_gearbox_opt.Value='All Combinations';
                app.input_small_overlap.Value='All Combinations';
                app.input_trunk_type_front.Value='All Combinations';
                app.input_trunk_type_rear.Value='All Combinations';
                app.input_trunk_ratio_min.Value=0;
                app.input_int_height_min.Value=300;
                app.input_gear_ratio_1_front_min.Value=6;
                app.input_gear_ratio_1_front_max.Value=10;
                app.input_gear_ratio_1_rear_min.Value=7;
                app.input_engine_angle_front_min.Value=0;
                app.input_engine_angle_rear_min.Value=0;
                app.input_axle_angle_1_min.Value=1;
                app.input_axle_angle_2_min.Value=1;
                app.input_tire_diam_min.Value=600;
                app.input_dist_wheelhouse2bumper_f_min.Value=0;
                app.input_dist_wheelhouse2bumper_r_min.Value=0;
                app.input_dist_int2fw_min.Value=0;
                app.input_dist_int2rw_min.Value=0;
                app.input_steering_ratio_min.Value=50;
                app.input_torque_ratio_min.Value=5;
                
                if app.input_trunk_volume.Value>0
                    app.input_trunk_ratio_min.Visible='on';
                else
                    app.input_trunk_ratio_min.Visible='off';
                end
                
                % Make Max values visible
                if app.input_trunk_volume.Value>0
                    app.input_trunk_ratio_max.Visible='on';
                    app.MaxtrunkratiofrontrearLabel.Visible='on';
                    app.input_trunk_ratio_min.Visible='on';
                    app.MintrunkratiofrontrearLabel.Visible='on';
                end
                
                app.input_gear_ratio_1_rear_max.Visible='on';
                app.input_gear_ratio_1_front_max.Visible='on';
                app.input_engine_angle_front_max.Visible='on';
                app.input_engine_angle_rear_max.Visible='on';
                app.input_engine_angle_front_min.Visible='on';
                app.input_engine_angle_rear_min.Visible='on';
                app.input_axle_angle_1_max.Visible='on';
                app.input_axle_angle_2_max.Visible='on';
                app.input_tire_diam_max.Visible='on';
                app.input_int_height_max.Visible='on';
                app.input_dist_wheelhouse2bumper_f_max.Visible='on';
                app.input_dist_wheelhouse2bumper_r_max.Visible='on';
                app.input_dist_int2fw_max.Visible='on';
                app.input_dist_int2rw_max.Visible='on';
                app.input_steering_ratio_max.Visible='on';
                app.input_engine_angle_rear_max.Visible='on';
                app.input_torque_ratio_max.Visible='on';
                app.input_torque_ratio_min.Visible='on';
                app.input_maxH30_front.Visible='on';
                app.input_maxH30_rear.Visible='on';
                app.input_window_angle_front_max.Visible='on';
                app.input_window_angle_rear_max.Visible='on';                
                                
                % Make Rear axle invisible
                app.input_gear_ratio_1_rear_min.Value=6;
                app.input_gear_ratio_1_rear_max.Value=10;
                app.input_gear_ratio_1_rear_min.Visible='on';
                app.input_gear_ratio_1_rear_max.Visible='on';
                app.Mingearratio1rearLabel.Visible='on';
                app.Maxgearratio1rearLabel.Visible='on';
                
                app.input_engine_angle_rear_min.Value=0;
                app.input_engine_angle_rear_max.Value=180;
                app.input_engine_angle_rear_min.Visible='on';
                app.input_engine_angle_rear_max.Visible='on';
                app.MinengineanglereardegLabel.Visible='on';
                app.MaxengineanglereardegLabel.Visible='on';
                
                app.GeartypeRearLabel.Visible='on';
                app.input_gear_type_rear.Visible='on';
                app.GeartypeFrontLabel.Visible='on';
                app.input_gear_type_front.Visible='on';
                
                % Change Min Labels and make max lables invisible
                app.MintirediametermmLabel.Text='Min. Tire diameter [mm]';
                app.MaxtirediametermmLabel.Visible='on';
                
                app.MininteriorheightmmLabel.Text='Min. Interior height [mm]';
                app.MaxinteriorheightmmLabel.Visible='on';
                
                app.MinaxleanglexydegLabel.Text='Min. Axle angle (xy) [deg]';
                app.MaxaxleanglexydegLabel.Visible='on';
                
                app.MinaxleanglexzdegLabel.Text='Min. Axle angle (xz) [deg]';
                app.MaxaxleanglexzdegLabel.Visible='on';
                
                app.Mingearratio1frontLabel.Text='Min. Gear ratio 1 front';
                app.Mingearratio1frontLabel.Visible='on';
                app.Maxgearratio1frontLabel.Visible='on';
                
                app.Mingearratio1rearLabel.Text='Min. Gear ratio 1 rear';
                app.Mingearratio1rearLabel.Visible='on';
                app.Maxgearratio1rearLabel.Visible='on';
                
                app.MintrunkratiofrontrearLabel.Text='Min. Trunk ratio front [%]';
                if app.input_trunk_volume.Value>0
                    app.MintrunkratiofrontrearLabel.Visible='on';
                    app.MaxtrunkratiofrontrearLabel.Visible='on';
                else
                    app.MintrunkratiofrontrearLabel.Visible='off';
                    app.MaxtrunkratiofrontrearLabel.Visible='off';
                end
                
                app.MinengineanglereardegLabel.Text='Min. Engine angle rear [deg]';
                app.MinengineanglereardegLabel.Visible='on';
                app.MaxengineanglereardegLabel.Visible='on';
                
                app.MinengineanglefrontdegLabel.Text='Min. Engine angle front [deg]';
                app.MaxengineanglefrontdegLabel.Visible='on';
                
                
                app.MindistancewheelhousebumpermmLabel.Text='Min. Distance Wheelhouse-Bumper [mm]';
                app.MaxdistancewheelhousebumpermmLabel.Visible='on';
                
                app.MindistanceinteriortofrontwagonmmLabel.Text='Min. distance interior to front wagon [mm]';
                app.MaxdistanceinteriortofrontwagonmmLabel.Visible='on';
                
                app.MinsteeringdistributionfrontLabel.Text='Min. Steering distribution front [%]';
                app.MaxsteeringdistributionfrontLabel.Visible='on';
                
                app.MintorqueratiofrontLabel.Text='Min. torque ratio front [%]';
                app.MaxtorqueratiofrontLabel.Visible='on';
                app.MintorqueratiofrontLabel.Visible='on';
                
                app.MaxH30frontLabel.Visible  = 'on';
                app.MaxH30rearLabel.Visible  = 'on';
                app.MinH30frontLabel.Text  = 'Min. H30 front';
                app.MinH30rearLabel.Text  = 'Min. H30 rear';
                
                app.MaxfrontwindowangleLabel.Visible = 'on';
                app.MaxrearwindowangleLabel.Visible = 'on';
                app.MinfrontwindowangleLabel.Text = 'Min. front window angle [°]';
                app.MinrearwindowangleLabel.Text = 'Min. rear window angle [°]';                
            end            
            
        end

        % Value changed function: input_tire_diam_min
        function input_tire_diam_minValueChanged(app, event)
            value = app.input_tire_diam_min.Value;
            if app.sing_veh.Value==1
                app.input_tire_diam_max.Value=value;
            end
        end

        % Value changed function: input_axle_angle_1_min
        function input_axle_angle_1_minValueChanged(app, event)
            value = app.input_axle_angle_1_min.Value;
            if app.sing_veh.Value==1
                app.input_axle_angle_1_max.Value=value;
            end
        end

        % Value changed function: input_axle_angle_2_min
        function input_axle_angle_2_minValueChanged(app, event)
            value = app.input_axle_angle_2_min.Value;
            if app.sing_veh.Value==1
                app.input_axle_angle_2_max.Value=value;
            end
        end

        % Value changed function: input_engine_angle_front_min
        function input_engine_angle_front_minValueChanged(app, event)
            value = app.input_engine_angle_front_min.Value;
            if app.sing_veh.Value==1
                app.input_engine_angle_front_max.Value=value;
            end
        end

        % Value changed function: input_engine_angle_rear_min
        function input_engine_angle_rear_minValueChanged(app, event)
            value = app.input_engine_angle_rear_min.Value;
            if app.sing_veh.Value==1
                app.input_engine_angle_rear_max.Value=value;
            end
        end

        % Value changed function: input_gear_ratio_1_front_min
        function input_gear_ratio_1_front_minValueChanged(app, event)
            value = app.input_gear_ratio_1_front_min.Value;
            if app.sing_veh.Value==1
                app.input_gear_ratio_1_front_max.Value=value;
            end
        end

        % Value changed function: input_gear_ratio_1_rear_min
        function input_gear_ratio_1_rear_minValueChanged(app, event)
            value = app.input_gear_ratio_1_rear_min.Value;
            if app.sing_veh.Value==1
                app.input_gear_ratio_1_rear_max.Value=value;
            end
        end

        % Callback function
        function input_batt_ratio_minValueChanged(app, event)
            
        end

        % Value changed function: input_drive_type
        function input_drive_typeValueChanged(app, event)
            % Changes the visibility of the input boxes depending if
            % single vehicle or optimization and dependinding on the
            % drive_type
            
                      
            value = app.input_drive_type.Value;
            if strcmp(value,'GM_X') || strcmp(value,'2G2M_X')
                
                % Make Rear axle invisible
                app.input_gear_ratio_1_rear_min.Value=10;
                app.input_gear_ratio_1_rear_max.Value=10;
                app.input_gear_ratio_1_rear_min.Visible='off';
                app.input_gear_ratio_1_rear_max.Visible='off';
                app.Mingearratio1rearLabel.Visible='off';
                app.Maxgearratio1rearLabel.Visible='off';
                
                app.input_engine_angle_rear_min.Value=0;
                app.input_engine_angle_rear_max.Value=0;
                app.input_engine_angle_rear_min.Visible='off';
                app.input_engine_angle_rear_max.Visible='off';
                app.MinengineanglereardegLabel.Visible='off';
                app.MaxengineanglereardegLabel.Visible='off';
                
                app.MintorqueratiofrontLabel.Visible='off';
                app.MaxtorqueratiofrontLabel.Visible='off';
                app.input_torque_ratio_min.Visible='off';
                app.input_torque_ratio_max.Visible='off';
                
                app.GeartypeRearLabel.Visible='off';
                app.input_gear_type_rear.Visible='off';
                app.GeartypeFrontLabel.Visible='on';
                app.input_gear_type_front.Visible='on';
                
                if app.sing_veh.Value==0 % Optimization FWD
                    % Make front axle visible
                    app.input_gear_ratio_1_front_min.Value=6;
                    app.input_gear_ratio_1_front_max.Value=10;
                    app.input_gear_ratio_1_front_min.Visible='on';
                    app.input_gear_ratio_1_front_max.Visible='on';
                    app.Mingearratio1frontLabel.Visible='on';
                    app.Maxgearratio1frontLabel.Visible='on';
                    
                    app.input_engine_angle_front_min.Value=0;
                    app.input_engine_angle_front_max.Value=180;
                    app.input_engine_angle_front_min.Visible='on';
                    app.input_engine_angle_front_max.Visible='on';
                    app.MinengineanglefrontdegLabel.Visible='on';
                    app.MaxengineanglefrontdegLabel.Visible='on';
                    
                else % Single vehicle FWD
                    % Make front axle visible
                    app.input_gear_ratio_1_front_min.Value=10;
                    app.input_gear_ratio_1_front_max.Value=10;
                    app.input_gear_ratio_1_front_min.Visible='on';
                    app.input_gear_ratio_1_front_max.Visible='off';
                    app.Mingearratio1frontLabel.Visible='on';
                    app.Maxgearratio1frontLabel.Visible='off';
                    
                    app.input_engine_angle_front_min.Value=0;
                    app.input_engine_angle_front_max.Value=180;
                    app.input_engine_angle_front_min.Visible='on';
                    app.input_engine_angle_front_max.Visible='off';
                    app.MinengineanglefrontdegLabel.Visible='on';
                    app.MaxengineanglefrontdegLabel.Visible='off';
                end
                
            elseif strcmp(value,'X_GM') || strcmp(value,'X_2G2M')
                % Make Front axle invisible
                app.input_gear_ratio_1_front_min.Value=10;
                app.input_gear_ratio_1_front_max.Value=10;
                app.input_gear_ratio_1_front_min.Visible='off';
                app.input_gear_ratio_1_front_max.Visible='off';
                app.Mingearratio1frontLabel.Visible='off';
                app.Maxgearratio1frontLabel.Visible='off';
                
                app.input_engine_angle_front_min.Value=0;
                app.input_engine_angle_front_max.Value=0;
                app.input_engine_angle_front_min.Visible='off';
                app.input_engine_angle_front_max.Visible='off';
                app.MinengineanglefrontdegLabel.Visible='off';
                app.MaxengineanglefrontdegLabel.Visible='off';
                
                app.MintorqueratiofrontLabel.Visible='off';
                app.MaxtorqueratiofrontLabel.Visible='off';
                app.input_torque_ratio_min.Visible='off';
                app.input_torque_ratio_max.Visible='off';
                
                app.GeartypeFrontLabel.Visible='off';
                app.input_gear_type_front.Visible='off';
                app.GeartypeRearLabel.Visible='on';
                app.input_gear_type_rear.Visible='on';
                
                if app.sing_veh.Value==0 % Optimization RWD
                    % Make rear axle visible
                    app.input_gear_ratio_1_rear_min.Value=6;
                    app.input_gear_ratio_1_rear_max.Value=10;
                    app.input_gear_ratio_1_rear_min.Visible='on';
                    app.input_gear_ratio_1_rear_max.Visible='on';
                    app.Mingearratio1rearLabel.Visible='on';
                    app.Maxgearratio1rearLabel.Visible='on';
                    
                    app.input_engine_angle_rear_min.Value=0;
                    app.input_engine_angle_rear_max.Value=180;
                    app.input_engine_angle_rear_min.Visible='on';
                    app.input_engine_angle_rear_max.Visible='on';
                    app.MinengineanglereardegLabel.Visible='on';
                    app.MaxengineanglereardegLabel.Visible='on';
                    
                else % Single vehicle RWD
                    % Make rear axle visible
                    app.input_gear_ratio_1_rear_min.Value=10;
                    app.input_gear_ratio_1_rear_max.Value=10;
                    app.input_gear_ratio_1_rear_min.Visible='on';
                    app.input_gear_ratio_1_rear_max.Visible='off';
                    app.Mingearratio1rearLabel.Visible='on';
                    app.Maxgearratio1rearLabel.Visible='off';
                    
                    app.input_engine_angle_rear_min.Value=0;
                    app.input_engine_angle_rear_max.Value=0;
                    app.input_engine_angle_rear_min.Visible='on';
                    app.input_engine_angle_rear_max.Visible='off';
                    app.MinengineanglereardegLabel.Visible='on';
                    app.MaxengineanglereardegLabel.Visible='off';
                end
            else % AWD
                app.GeartypeFrontLabel.Visible='on';
                app.input_gear_type_front.Visible='on';
                app.GeartypeRearLabel.Visible='on';
                app.input_gear_type_rear.Visible='on';
                
                app.MintorqueratiofrontLabel.Visible='on';
                app.input_torque_ratio_min.Visible='on';
                
                if app.sing_veh.Value==0
                    % Make front axle visible
                    app.input_gear_ratio_1_front_min.Value=6;
                    app.input_gear_ratio_1_front_max.Value=10;
                    app.input_gear_ratio_1_front_min.Visible='on';
                    app.input_gear_ratio_1_front_max.Visible='on';
                    app.Mingearratio1frontLabel.Visible='on';
                    app.Maxgearratio1frontLabel.Visible='on';
                    
                    app.input_engine_angle_front_min.Value=0;
                    app.input_engine_angle_front_max.Value=180;
                    app.input_engine_angle_front_min.Visible='on';
                    app.input_engine_angle_front_max.Visible='on';
                    app.MinengineanglefrontdegLabel.Visible='on';
                    app.MaxengineanglefrontdegLabel.Visible='on';
                    
                    % Make rear axle visible
                    app.input_gear_ratio_1_rear_min.Value=10;
                    app.input_gear_ratio_1_rear_max.Value=10;
                    app.input_gear_ratio_1_rear_min.Visible='on';
                    app.input_gear_ratio_1_rear_max.Visible='on';
                    app.Mingearratio1rearLabel.Visible='on';
                    app.Maxgearratio1rearLabel.Visible='on';
                    
                    app.input_engine_angle_rear_min.Value=0;
                    app.input_engine_angle_rear_max.Value=180;
                    app.input_engine_angle_rear_min.Visible='on';
                    app.input_engine_angle_rear_max.Visible='on';
                    app.MinengineanglereardegLabel.Visible='on';
                    app.MaxengineanglereardegLabel.Visible='on';
                    
                    app.MaxtorqueratiofrontLabel.Visible='on';
                    app.input_torque_ratio_max.Visible='on';
                    
                else
                    % Make front axle visible
                    app.input_gear_ratio_1_front_min.Value=10;
                    app.input_gear_ratio_1_front_max.Value=10;
                    app.input_gear_ratio_1_front_min.Visible='on';
                    app.input_gear_ratio_1_front_max.Visible='off';
                    app.Mingearratio1frontLabel.Visible='on';
                    app.Maxgearratio1frontLabel.Visible='off';
                    
                    app.input_engine_angle_front_min.Value=0;
                    app.input_engine_angle_front_max.Value=0;
                    app.input_engine_angle_front_min.Visible='on';
                    app.input_engine_angle_front_max.Visible='off';
                    app.MinengineanglefrontdegLabel.Visible='on';
                    app.MaxengineanglefrontdegLabel.Visible='off';
                    
                    % Make rear axle visible
                    app.input_gear_ratio_1_rear_min.Value=10;
                    app.input_gear_ratio_1_rear_max.Value=10;
                    app.input_gear_ratio_1_rear_min.Visible='on';
                    app.input_gear_ratio_1_rear_max.Visible='off';
                    app.Mingearratio1rearLabel.Visible='on';
                    app.Maxgearratio1rearLabel.Visible='off';
                    
                    app.input_engine_angle_rear_min.Value=0;
                    app.input_engine_angle_rear_max.Value=0;
                    app.input_engine_angle_rear_min.Visible='on';
                    app.input_engine_angle_rear_max.Visible='off';
                    app.MinengineanglereardegLabel.Visible='on';
                    app.MaxengineanglereardegLabel.Visible='off';
                    
                    app.MaxtorqueratiofrontLabel.Visible='off';
                    app.input_torque_ratio_max.Visible='off';
                    
                end
            end
        end

        % Value changed function: input_gear_type_front
        function input_gear_type_frontValueChanged(app, event)
            value = app.input_gear_type_front.Value;
            value2 = app.input_drive_type.Value;
            if strcmp(value2,'GM_X') || strcmp(value2,'2G2M_X')
                app.input_gear_type_rear.Value = value;
            end
        end

        % Value changed function: input_gear_type_rear
        function input_gear_type_rearValueChanged(app, event)
            value = app.input_gear_type_rear.Value;
            value2 = app.input_drive_type.Value;
            if strcmp(value2,'X_GM') || strcmp(value2,'X_2G2M')
                app.input_gear_type_front.Value = value;
            end
        end

        % Value changed function: input_trunk_ratio_min
        function input_trunk_ratio_minValueChanged(app, event)
            value = app.input_trunk_ratio_min.Value;
            if app.sing_veh.Value==1
                app.input_trunk_ratio_max.Value=value;
            end
        end

        % Value changed function: input_int_height_min
        function input_int_height_minValueChanged(app, event)
            value = app.input_int_height_min.Value;
            if app.sing_veh.Value==1
                app.input_int_height_max.Value=value;
            end
        end

        % Button pushed function: SAVECONFIGURATIONButton
        function SAVECONFIGURATIONButtonPushed(app, event)
            % Saves the selected inputs in a Matrix
            props = properties(app);
            values = cell(1, length(props));
            visibilities = cell(1, length(props));
            
            for i = 1:length(props)
                propName = props{i};
                property = app.(propName);
                if isprop(property, 'Value')
                    values{i} = app.(propName).Value;
                end
                if isprop(property, 'Visible')
                    visibilities{i} = app.(props{i}).Visible;
                end
            end
            
            
            [file,path] = uiputfile('*.mat', "Save Message" );
            drawnow;            
            
            if path==0
            else
            addpath(path);
            full_filename=[path file];
            end
            if file
                save(full_filename, 'props', 'values', 'visibilities');
            end         
            
        end

        % Button pushed function: LOADCONFIGURATIONButton
        function LOADCONFIGURATIONButtonPushed(app, event)
            % Reads and loads the selected input stored in a Matrix
            [file,path] = uigetfile('*.mat', "Load Message");
            drawnow;
            figure(app.GUIFahrzeugkonzepteUIFigure)
            if path==0
            else
                addpath(path);
                if file
                    load(file, 'props', 'values', 'visibilities');
                end
                
                for i = 1:length(props)
                    propName = props{i};
                    property = app.(propName);
                    if isprop(property, 'Value')
                        app.(propName).Value = values{i};
                    end
                    if isprop(property, 'Visible')
                        app.(props{i}).Visible = visibilities{i};
                    end
                end
                limit_backrestthickness(app);
                H30_Optimization(app);
                % initialize default folder name for optimization results                
                UpdateOptimizerPanel(app)
            end
        end

        % Value changed function: input_trunk_volume
        function input_trunk_volumeValueChanged(app, event)
            value = app.input_trunk_volume.Value;
            % Makes trunk ratio invisible if trunk volume is set to zero
            if app.sing_veh.Value==1
                if value>0
                    app.input_trunk_ratio_min.Visible='on';
                    app.MintrunkratiofrontrearLabel.Visible='on';
                else
                    app.input_trunk_ratio_min.Visible='off';
                    app.MintrunkratiofrontrearLabel.Visible='off';
                end
            else
                if value>0
                    app.input_trunk_ratio_min.Visible='on';
                    app.MintrunkratiofrontrearLabel.Visible='on';
                    app.input_trunk_ratio_max.Visible='on';
                    app.MaxtrunkratiofrontrearLabel.Visible='on';
                else
                    app.input_trunk_ratio_min.Visible='off';
                    app.MintrunkratiofrontrearLabel.Visible='off';
                    app.input_trunk_ratio_max.Visible='off';
                    app.MaxtrunkratiofrontrearLabel.Visible='off';
                end
            end
        end

        % Value changed function: input_goal_1
        function input_goal_1ValueChanged(app, event)
            
        end

        % Value changed function: input_int_usecase
        function input_int_usecaseValueChanged(app, event)
            UseCase(app);          
        end

        % Value changed function: input_int_angle_br_max_1
        function input_int_angle_br_max_1ValueChanged(app, event)
            limit_backrestangle(app);
            
        end

        % Value changed function: input_int_angle_br_min_1
        function input_int_angle_br_min_1ValueChanged(app, event)
           limit_backrestangle(app);
            
        end

        % Value changed function: input_int_angle_br_max_2
        function input_int_angle_br_max_2ValueChanged(app, event)
           limit_backrestangle(app);
            
        end

        % Value changed function: input_int_angle_br_min_2
        function input_int_angle_br_min_2ValueChanged(app, event)
            limit_backrestangle(app);
        end

        % Value changed function: input_int_thickness_seat_1
        function input_int_thickness_seat_1ValueChanged(app, event)
            limit_backrestthickness(app);
            seatheight(app)
            H30_Check(app)
        end

        % Value changed function: input_int_thickness_seat_2
        function input_int_thickness_seat_2ValueChanged(app, event)
            limit_backrestthickness(app);
            seatheight(app)
            H30_Check(app)
        end

        % Value changed function: input_int_work
        function input_int_workValueChanged(app, event)
            UseCase(app)
        end

        % Value changed function: input_int_sleep
        function input_int_sleepValueChanged(app, event)
            UseCase(app)
        end

        % Value changed function: input_int_single_ar_between_2
        function input_int_single_ar_between_2ValueChanged(app, event)
            armrest(app)       
        end

        % Value changed function: input_int_double_ar_2
        function input_int_double_ar_2ValueChanged(app, event)
            armrest(app)     
        end

        % Value changed function: input_seats_row_1
        function input_seats_row_1ValueChanged(app, event)
            value = app.input_seats_row_1.Value;
            
            if value == 1
                app.input_int_single_ar_between_1.Enable = 'off';
                app.input_int_double_ar_1.Enable = 'off';
            end
            
            if value >1
               app.input_int_single_ar_between_1.Enable = 'on';
               app.input_int_double_ar_1.Enable = 'on'; 
            end
            armrest(app)
        end

        % Value changed function: input_seats_row_2
        function input_seats_row_2ValueChanged(app, event)
            value = app.input_seats_row_2.Value;
            
            if value == 1
                app.input_int_single_ar_between_2.Enable = 'off';
                app.input_int_double_ar_2.Enable = 'off';
            end
            
            if value >1
               app.input_int_single_ar_between_2.Enable = 'on';
               app.input_int_double_ar_2.Enable = 'on'; 
            end
            armrest(app)
        end

        % Value changed function: input_int_single_ar_between_1
        function input_int_single_ar_between_1ValueChanged(app, event)
            armrest(app)
        end

        % Value changed function: input_int_double_ar_1
        function input_int_double_ar_1ValueChanged(app, event)
            armrest(app)    
        end

        % Value changed function: input_weather_scenario
        function input_weather_scenarioValueChanged(app, event)
            value = app.input_weather_scenario.Value;
            if strcmp(value,'Munich - Summer')
                app.input_ambient_temp.Items={'32','30','28','25'};
                app.input_ambient_temp.Value='25';
                app.input_percentage_circair.Enable = 'off';
                app.input_massflow_rate.Enable = 'off';
                app.input_temperature_set.Enable='off';
            elseif strcmp(value,'Munich - Winter')
                app.input_ambient_temp.Items={'5','4','2','0','-2','-5','-7','-8'};
                app.input_ambient_temp.Value='5';
                app.input_percentage_circair.Enable = 'on';
                app.input_massflow_rate.Enable = 'on';
                app.input_temperature_set.Enable='on';
            elseif strcmp(value,'Munich - Spring/Fall')
                app.input_ambient_temp.Items={'10'};
                app.input_percentage_circair.Enable = 'off';
                app.input_massflow_rate.Enable = 'off';
                app.input_temperature_set.Enable='off';
            end
        end

        % Value changed function: input_int_seat_height_1
        function input_int_seat_height_1ValueChanged(app, event)
            seatheight(app)
        end

        % Value changed function: input_int_seat_height_2
        function input_int_seat_height_2ValueChanged(app, event)
            seatheight(app)
        end

        % Value changed function: input_int_seat_adjustment_z_1
        function input_int_seat_adjustment_z_1ValueChanged(app, event)
           seatheight(app)
        end

        % Value changed function: input_int_seat_adjustment_z_2
        function input_int_seat_adjustment_z_2ValueChanged(app, event)
           seatheight(app)
        end

        % Value changed function: input_H30Opt
        function input_H30OptValueChanged(app, event)
            value = app.input_H30Opt.Value;
            H30_Optimization(app)
        end

        % Value changing function: input_minH30_front
        function input_minH30_frontValueChanging(app, event)
            changingValue = event.Value;
            H30_Check(app)
        end

        % Value changing function: input_minH30_rear
        function input_minH30_rearValueChanging(app, event)
            changingValue = event.Value;
            H30_Check(app)
        end

        % Value changing function: input_maxH30_front
        function input_maxH30_frontValueChanging(app, event)
            changingValue = event.Value;
            H30_Check(app)
        end

        % Value changing function: input_maxH30_rear
        function input_maxH30_rearValueChanging(app, event)
            changingValue = event.Value;
            H30_Check(app)
        end

        % Button pushed function: 
        % SAVESETTINGSFOREXTERNALSIMULATIONButton
        function SAVESETTINGSFOREXTERNALSIMULATIONButtonPushed(app, event)
            % Function by Fabian
            % This button saved Parameters and optimization options in
            % SavedOptimizationConfiguration.mat which are needed to run
            % simulation using script OptimizationScript.m
            
            % OptimizationVector to define which optimizer is
            % selected by the user
            OptimizationVector = [app.NSGAIICheckBox.Value, app.MOPSOCheckBox.Value,...
                app.MultipopulationNSGAIICheckBox.Value, app.MultiswarmMOPSOCheckBox.Value];
            
            % check if user selected an optimizer a
            if sum(OptimizationVector) == 0
                % inform user in case no optimizer is selected
                disp("No valid optimizer used");
                return;
            else
                % find the index of the chosen optimizer for
                % Startfile if a selected optimizer is found
                app.OptimizerIndex = find(OptimizationVector == 1);
            end
            
            % starts conversion of app to Parameter struct
            Parameters=ConvertUserform(app); 
            
            % initializes optimization options and specify messages
            if Parameters.optimization.NSGA == 1
                [options,Parameters] = NSGAII_Options(Parameters);
            elseif Parameters.optimization.MOPSO == 1
                [options,Parameters] = MOPSO_Options(Parameters);
            elseif Parameters.optimization.MPNSGA == 1
                [options,Parameters] = MultiPopNSGAII_Options(Parameters);
            elseif Parameters.optimization.MSMOPSO == 1
                [options,Parameters] = MultiSwarmMOPSO_Options(Parameters);
            end
            
            % save number of optimization
            options.RepeatNumber = app.NumberofOptimizationSpinner.Value;
            
            %Select folder and name          
            [file,path] = uiputfile('*.mat', "Save Message" );
            drawnow;            
            
            if path==0
            else
            addpath(path);
            full_filename=[path file];
            end
            
            if file                
                save(full_filename,"options","Parameters");
            end               
            
            figure(app.GUIFahrzeugkonzepteUIFigure)
            
        end

        % Value changed function: settings_conv
        function settings_convValueChanged(app, event)
            if app.settings_conv.Value==1               
                app.settings_aut.Value=0;
            end           
            sett_conv = app.settings_conv.Value;
            sett_aut = app.settings_aut.Value;
            app = Set_vehicle_settings(app,sett_aut,sett_conv);
        end

        % Value changed function: settings_aut
        function settings_autValueChanged(app, event)
            if app.settings_aut.Value==1                
                app.settings_conv.Value=0;
            end
            sett_aut = app.settings_aut.Value;
            sett_conv = app.settings_conv.Value;
            app = Set_vehicle_settings(app,sett_aut,sett_conv);
        end

        % Value changed function: MOPSOCheckBox
        function MOPSOCheckBoxValueChanged(app, event)
            % Function by Fabian
            
            % update optimizer panel if MOPSO is selected
            UpdateOptimizerPanel(app);            
        end

        % Value changed function: MultiswarmMOPSOCheckBox
        function MultiswarmMOPSOCheckBoxValueChanged(app, event)
            % Function by Fabian
            
            % update optimizer panel if Multiswarm MOPSO is selected
            UpdateOptimizerPanel(app);            
        end

        % Value changed function: NSGAIICheckBox
        function NSGAIICheckBoxValueChanged(app, event)
            % Function by Fabian
            
            % update optimizer panel if NSGA-II is selected
            UpdateOptimizerPanel(app);            
        end

        % Value changed function: MultipopulationNSGAIICheckBox
        function MultipopulationNSGAIICheckBoxValueChanged(app, event)
            % Function by Fabian
            
            % update optimizer panel if Multipopulation NSGA-II is selected
            UpdateOptimizerPanel(app);            
        end

        % Button pushed function: SAVEOPTIMIZATIONPARAMETERSButton
        function SAVEOPTIMIZATIONPARAMETERSButtonPushed(app, event)
            % Function by Fabian
            % This button saved Parameters and optimization options in
            % SavedOptimizationConfiguration.mat which are needed to run
            % simulation using script OptimizationScript.m
            
            % OptimizationVector to define which optimizer is
            % selected by the user
            OptimizationVector = [app.NSGAIICheckBox.Value, app.MOPSOCheckBox.Value,...
                app.MultipopulationNSGAIICheckBox.Value, app.MultiswarmMOPSOCheckBox.Value];
            
            % check if user selected an optimizer a
            if sum(OptimizationVector) == 0
                % inform user in case no optimizer is selected
                disp("No valid optimizer used");
                return;
            else
                % find the index of the chosen optimizer for
                % Startfile if a selected optimizer is found
                app.OptimizerIndex = find(OptimizationVector == 1);
            end
            
            % starts conversion of app to Parameter struct
            Parameters=ConvertUserform(app); 
            
            % initializes optimization options and specify messages
            if Parameters.optimization.NSGA == 1
                [options,Parameters] = NSGAII_Options(Parameters);
            elseif Parameters.optimization.MOPSO == 1
                [options,Parameters] = MOPSO_Options(Parameters);
            elseif Parameters.optimization.MPNSGA == 1
                [options,Parameters] = MultiPopNSGAII_Options(Parameters);
            elseif Parameters.optimization.MSMOPSO == 1
                [options,Parameters] = MultiSwarmMOPSO_Options(Parameters);
            end
            
            % save number of optimization
            options.RepeatNumber = app.NumberofOptimizationSpinner.Value;
            
            % get path in where OptimizationScript.m is located
            Folder=fileparts(which("OptimizationScript.m"));
            % save SavedOptimizationConfiguration.mat in the same folder
            save(fullfile(Folder,"SavedOptimizationConfiguration.mat"),"options","Parameters");
        end

        % Button pushed function: SelectOptimizationFolder
        function SelectOptimizationFolderPushed(app, event)
            % Function by Fabian
            % This button defines the folder for saving optimization
            % results using dialog box of uigetdir
            
            % get full path for folder name where the optimization result
            % is going to be stored
            app.SaveFolderName = uigetdir;
            % if user selects a valid path
            if app.SaveFolderName ~= 0
                % show the full path for the SaveFolderName in SaveFolder
                % textbox
                app.SaveFolder.Value = app.SaveFolderName;
                % disable textbox to prevent error
                app.SaveFolder.Enable = false;
            end
            
            % show the userform in foreground
            drawnow;
            figure(app.GUIFahrzeugkonzepteUIFigure);
        end

        % Button pushed function: ResetPathButton
        function ResetPathButtonPushed(app, event)
            % reset default folder name for optimization results
            GetLastDefaultFolders(app); % edit by Fabian
        end

        % Value changed function: input_seating_layout
        function input_seating_layoutValueChanged(app, event)
            value = app.input_seating_layout.Value;
            if strcmp(value,'Back-to-Back')
                app.input_int_b2b_walldistance.Enable=1;
                app.WalldistancemmLabel_3.Enable=1;
            else
                app.input_int_b2b_walldistance.Enable=0;
                app.WalldistancemmLabel_3.Enable=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIFahrzeugkonzepteUIFigure = uifigure('Visible', 'off');
            app.GUIFahrzeugkonzepteUIFigure.Name = 'Autonomous vehicle concept tool';
            app.RunSimulation = uibutton(app.GUIFahrzeugkonzepteUIFigure, 'push');
            app.RunSimulation.ButtonPushedFcn = createCallbackFcn(app, @StartSimulation, true);
            app.RunSimulation.Text = 'Run simulation';
            app.FTM_Logo = uibutton(app.GUIFahrzeugkonzepteUIFigure, 'push');
            app.FTM_Logo.ButtonPushedFcn = createCallbackFcn(app, @FTM_LogoPushed, true);
            app.FTM_Logo.Icon = 'ftm_logo.jpg';
            app.FTM_Logo.Text = '';
            app.TabGroup = uitabgroup(app.GUIFahrzeugkonzepteUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = '1. General';
            app.LongitudinalDynamicPanel = uipanel(app.GeneralTab);
            app.LongitudinalDynamicPanel.Title = 'Longitudinal Dynamic';
            app.DrivingcycleLabel = uilabel(app.LongitudinalDynamicPanel);
            app.DrivingcycleLabel.Text = 'Driving cycle';
            app.input_driving_cycle = uidropdown(app.LongitudinalDynamicPanel);
            app.input_driving_cycle.Items = {'WLTP', 'FTP72', 'FTP75', 'US_Highway', 'NEFZ', 'EUROPE_ARTEMIS_URBAN', 'EUROPE_ARTEMIS_MOTORWAY'};
            app.input_driving_cycle.Value = 'WLTP';
            app.TopspeedinkmhLabel = uilabel(app.LongitudinalDynamicPanel);
            app.TopspeedinkmhLabel.Text = 'Top speed [km/h]';
            app.input_maximal_speed = uispinner(app.LongitudinalDynamicPanel);
            app.input_maximal_speed.Step = 10;
            app.input_maximal_speed.Limits = [50 250];
            app.input_maximal_speed.Value = 150;
            app.Minimumaccelerationtimefrom0to100kmhinsLabel = uilabel(app.LongitudinalDynamicPanel);
            app.Minimumaccelerationtimefrom0to100kmhinsLabel.Text = 'Minimum acceleration time (0 - 100km/h) [s]';
            app.input_maximal_acceleration = uispinner(app.LongitudinalDynamicPanel);
            app.input_maximal_acceleration.Step = 0.1;
            app.input_maximal_acceleration.Limits = [2 14];
            app.input_maximal_acceleration.ValueDisplayFormat = '%.1f';
            app.input_maximal_acceleration.Value = 10;
            app.MinrangekmLabel = uilabel(app.LongitudinalDynamicPanel);
            app.MinrangekmLabel.Text = 'Min. range [km]';
            app.input_range = uispinner(app.LongitudinalDynamicPanel);
            app.input_range.Step = 5;
            app.input_range.Limits = [50 2000];
            app.input_range.Value = 250;
            app.CompleteVehiclePanel = uipanel(app.GeneralTab);
            app.CompleteVehiclePanel.Title = 'Complete Vehicle';
            app.FreecrashlengthfrontmmLabel = uilabel(app.CompleteVehiclePanel);
            app.FreecrashlengthfrontmmLabel.Text = 'Free crash length front [mm]';
            app.input_free_crash_length = uispinner(app.CompleteVehiclePanel);
            app.input_free_crash_length.Step = 50;
            app.input_free_crash_length.Limits = [0 1500];
            app.input_free_crash_length.Value = 500;
            app.FreecrashlengthsidemmLabel = uilabel(app.CompleteVehiclePanel);
            app.FreecrashlengthsidemmLabel.Text = 'Free crash length side [mm]';
            app.input_free_crash_length_3 = uispinner(app.CompleteVehiclePanel);
            app.input_free_crash_length_3.Step = 20;
            app.input_free_crash_length_3.Limits = [0 500];
            app.input_free_crash_length_3.Value = 250;
            app.FreecrashlengthrearmmLabel = uilabel(app.CompleteVehiclePanel);
            app.FreecrashlengthrearmmLabel.Text = 'Free crash length rear [mm]';
            app.input_free_crash_length_2 = uispinner(app.CompleteVehiclePanel);
            app.input_free_crash_length_2.Step = 50;
            app.input_free_crash_length_2.Limits = [0 1500];
            app.input_free_crash_length_2.Value = 400;
            app.GroundclearanceLabel = uilabel(app.CompleteVehiclePanel);
            app.GroundclearanceLabel.Text = 'Ground clearance';
            app.input_ground_clearance = uidropdown(app.CompleteVehiclePanel);
            app.input_ground_clearance.Items = {'flatfloor', 'highfloor'};
            app.input_ground_clearance.Value = 'flatfloor';
            app.TotaltrunkvolumeLLabel = uilabel(app.CompleteVehiclePanel);
            app.TotaltrunkvolumeLLabel.Text = 'Total trunk volume [L] ';
            app.input_trunk_volume = uispinner(app.CompleteVehiclePanel);
            app.input_trunk_volume.Step = 20;
            app.input_trunk_volume.Limits = [0 2000];
            app.input_trunk_volume.ValueDisplayFormat = '%.0f';
            app.input_trunk_volume.ValueChangedFcn = createCallbackFcn(app, @input_trunk_volumeValueChanged, true);
            app.TurningdiametermLabel = uilabel(app.CompleteVehiclePanel);
            app.TurningdiametermLabel.Text = 'Turning diameter [m]';
            app.input_turning_diameter = uispinner(app.CompleteVehiclePanel);
            app.input_turning_diameter.Step = 0.5;
            app.input_turning_diameter.Limits = [0 20];
            app.input_turning_diameter.Value = 11;
            app.input_tire_load = uicheckbox(app.CompleteVehiclePanel);
            app.input_tire_load.Text = 'Extra load tires';
            app.input_tire_load.Value = true;
            app.settings_conv = uicheckbox(app.CompleteVehiclePanel);
            app.settings_conv.ValueChangedFcn = createCallbackFcn(app, @settings_convValueChanged, true);
            app.settings_conv.Text = 'Set conventional vehicle settings';
            app.settings_aut = uicheckbox(app.CompleteVehiclePanel);
            app.settings_aut.ValueChangedFcn = createCallbackFcn(app, @settings_autValueChanged, true);
            app.settings_aut.Text = 'Set autonomous one-box vehicle settings';
            app.HVACTab = uitab(app.TabGroup);
            app.HVACTab.Title = '2. HVAC';
            app.WeatherConditionsPanel = uipanel(app.HVACTab);
            app.WeatherConditionsPanel.Title = 'Weather Conditions';
            app.ScenarioLabel = uilabel(app.WeatherConditionsPanel);
            app.ScenarioLabel.Text = 'Scenario';
            app.input_weather_scenario = uidropdown(app.WeatherConditionsPanel);
            app.input_weather_scenario.Items = {'Munich - Winter', 'Munich - Spring/Fall', 'Munich - Summer'};
            app.input_weather_scenario.ValueChangedFcn = createCallbackFcn(app, @input_weather_scenarioValueChanged, true);
            app.input_weather_scenario.Value = 'Munich - Summer';
            app.MassflowratekgminLabel = uilabel(app.WeatherConditionsPanel);
            app.MassflowratekgminLabel.Text = 'Mass flow rate [kg/min]';
            app.input_massflow_rate = uispinner(app.WeatherConditionsPanel);
            app.input_massflow_rate.Step = 0.1;
            app.input_massflow_rate.Limits = [3 7];
            app.input_massflow_rate.ValueDisplayFormat = '%.1f';
            app.input_massflow_rate.Enable = 'off';
            app.input_massflow_rate.Value = 5;
            app.HVACcalculationLabel = uilabel(app.WeatherConditionsPanel);
            app.HVACcalculationLabel.Text = 'HVAC calculation';
            app.HVAC_calc = uicheckbox(app.WeatherConditionsPanel);
            app.AmbienttemperatureCDropDownLabel = uilabel(app.WeatherConditionsPanel);
            app.AmbienttemperatureCDropDownLabel.Text = 'Ambient temperature [°C]';
            app.input_ambient_temp = uidropdown(app.WeatherConditionsPanel);
            app.input_ambient_temp.Items = {'32', '30', '28', '25'};
            app.input_ambient_temp.Value = '25';
            app.RecirculationpercentageLabel = uilabel(app.WeatherConditionsPanel);
            app.RecirculationpercentageLabel.Text = 'Recirculation percentage [%]';
            app.input_percentage_circair = uispinner(app.WeatherConditionsPanel);
            app.input_percentage_circair.Step = 5;
            app.input_percentage_circair.Limits = [0 100];
            app.input_percentage_circair.Enable = 'off';
            app.SettemperatureCLabel_2 = uilabel(app.WeatherConditionsPanel);
            app.SettemperatureCLabel_2.Text = 'Set temperature [°C]';
            app.input_temperature_set = uidropdown(app.WeatherConditionsPanel);
            app.input_temperature_set.Items = {'22', '23', '24'};
            app.input_temperature_set.Enable = 'off';
            app.input_temperature_set.Value = '22';
            app.InteriorTab = uitab(app.TabGroup);
            app.InteriorTab.Title = '3. Interior';
            app.InteriorPanel_3 = uipanel(app.InteriorTab);
            app.InteriorPanel_3.Title = 'Interior';
            app.FirstrowLabel_2 = uilabel(app.InteriorPanel_3);
            app.FirstrowLabel_2.Text = 'First row';
            app.SecondrowLabel_2 = uilabel(app.InteriorPanel_3);
            app.SecondrowLabel_2.Text = 'Second row';
            app.SeatinglayoutLabel_2 = uilabel(app.InteriorPanel_3);
            app.SeatinglayoutLabel_2.Text = 'Seating layout';
            app.input_seating_layout = uidropdown(app.InteriorPanel_3);
            app.input_seating_layout.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout.ValueChangedFcn = createCallbackFcn(app, @input_seating_layoutValueChanged, true);
            app.input_seating_layout.Value = 'Vis-a-Vis';
            app.SeatsfirstrowLabel_3 = uilabel(app.InteriorPanel_3);
            app.SeatsfirstrowLabel_3.Text = 'Seats first row';
            app.input_seats_row_1 = uispinner(app.InteriorPanel_3);
            app.input_seats_row_1.Limits = [1 3];
            app.input_seats_row_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_seats_row_1.Value = 2;
            app.SeatssecondrowLabel_3 = uilabel(app.InteriorPanel_3);
            app.SeatssecondrowLabel_3.Text = 'Seats second row';
            app.input_seats_row_2 = uispinner(app.InteriorPanel_3);
            app.input_seats_row_2.Limits = [1 3];
            app.input_seats_row_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_seats_row_2.Value = 2;
            app.UpperBodylengthmmLabel = uilabel(app.InteriorPanel_3);
            app.UpperBodylengthmmLabel.Text = 'Upper Body length [mm]';
            app.input_int_upperbody_length_1 = uispinner(app.InteriorPanel_3);
            app.input_int_upperbody_length_1.Step = 25;
            app.input_int_upperbody_length_1.Limits = [400 1200];
            app.input_int_upperbody_length_1.Value = 980;
            app.BackrestanglemaxdegLabel = uilabel(app.InteriorPanel_3);
            app.BackrestanglemaxdegLabel.Text = 'Backrest angle (max) [deg]';
            app.input_int_angle_br_max_1 = uispinner(app.InteriorPanel_3);
            app.input_int_angle_br_max_1.Step = 5;
            app.input_int_angle_br_max_1.Limits = [1 90];
            app.input_int_angle_br_max_1.ValueChangedFcn = createCallbackFcn(app, @input_int_angle_br_max_1ValueChanged, true);
            app.input_int_angle_br_max_1.Tag = 'int.test';
            app.input_int_angle_br_max_1.Value = 80;
            app.HeadroommmLabel = uilabel(app.InteriorPanel_3);
            app.HeadroommmLabel.Text = 'Headroom [mm]';
            app.input_int_headroom_1 = uispinner(app.InteriorPanel_3);
            app.input_int_headroom_1.Step = 10;
            app.input_int_headroom_1.Limits = [0 500];
            app.input_int_headroom_1.Value = 150;
            app.SeatdepthmmLabel = uilabel(app.InteriorPanel_3);
            app.SeatdepthmmLabel.Text = 'Seat depth [mm]';
            app.input_int_seat_depth_1 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_depth_1.Step = 10;
            app.input_int_seat_depth_1.Limits = [5 800];
            app.input_int_seat_depth_1.Value = 460;
            app.SeatadjustmentxmmLabel = uilabel(app.InteriorPanel_3);
            app.SeatadjustmentxmmLabel.Text = 'Seat adjustment x [mm]';
            app.input_int_seat_adjustment_x_1 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_adjustment_x_1.Step = 5;
            app.input_int_seat_adjustment_x_1.Limits = [0 200];
            app.input_int_seat_adjustment_x_1.Value = 120;
            app.SeatheightmmLabel = uilabel(app.InteriorPanel_3);
            app.SeatheightmmLabel.Text = 'Seat height [mm]';
            app.input_int_seat_height_1 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_height_1.Step = 25;
            app.input_int_seat_height_1.Limits = [5 800];
            app.input_int_seat_height_1.ValueChangedFcn = createCallbackFcn(app, @input_int_seat_height_1ValueChanged, true);
            app.input_int_seat_height_1.Value = 400;
            app.SeatadjustmentzmmLabel = uilabel(app.InteriorPanel_3);
            app.SeatadjustmentzmmLabel.Text = 'Seat adjustment z [mm]';
            app.input_int_seat_adjustment_z_1 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_adjustment_z_1.Step = 10;
            app.input_int_seat_adjustment_z_1.Limits = [0 300];
            app.input_int_seat_adjustment_z_1.ValueChangedFcn = createCallbackFcn(app, @input_int_seat_adjustment_z_1ValueChanged, true);
            app.input_int_seat_adjustment_z_1.Value = 100;
            app.FootlengthmmLabel = uilabel(app.InteriorPanel_3);
            app.FootlengthmmLabel.Text = 'Foot length [mm]';
            app.input_int_foot_length_1 = uispinner(app.InteriorPanel_3);
            app.input_int_foot_length_1.Step = 5;
            app.input_int_foot_length_1.Limits = [0 800];
            app.input_int_foot_length_1.Value = 310;
            app.UpperBodylengthmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.UpperBodylengthmmLabel_2.Text = 'Upper Body length [mm]';
            app.input_int_upperbody_length_2 = uispinner(app.InteriorPanel_3);
            app.input_int_upperbody_length_2.Step = 25;
            app.input_int_upperbody_length_2.Limits = [400 1200];
            app.input_int_upperbody_length_2.Value = 980;
            app.BackrestanglemaxdegLabel_2 = uilabel(app.InteriorPanel_3);
            app.BackrestanglemaxdegLabel_2.Text = 'Backrest angle (max) [deg]';
            app.input_int_angle_br_max_2 = uispinner(app.InteriorPanel_3);
            app.input_int_angle_br_max_2.Step = 5;
            app.input_int_angle_br_max_2.Limits = [1 90];
            app.input_int_angle_br_max_2.ValueChangedFcn = createCallbackFcn(app, @input_int_angle_br_max_2ValueChanged, true);
            app.input_int_angle_br_max_2.Value = 80;
            app.HeadroommmLabel_2 = uilabel(app.InteriorPanel_3);
            app.HeadroommmLabel_2.Text = 'Headroom [mm]';
            app.input_int_headroom_2 = uispinner(app.InteriorPanel_3);
            app.input_int_headroom_2.Step = 10;
            app.input_int_headroom_2.Limits = [0 500];
            app.input_int_headroom_2.Value = 150;
            app.SeatdepthmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.SeatdepthmmLabel_2.Text = 'Seat depth [mm]';
            app.input_int_seat_depth_2 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_depth_2.Step = 10;
            app.input_int_seat_depth_2.Limits = [5 800];
            app.input_int_seat_depth_2.Value = 460;
            app.SeatadjustmentxmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.SeatadjustmentxmmLabel_2.Text = 'Seat adjustment x [mm]';
            app.input_int_seat_adjustment_x_2 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_adjustment_x_2.Step = 10;
            app.input_int_seat_adjustment_x_2.Limits = [0 200];
            app.input_int_seat_adjustment_x_2.Value = 120;
            app.SeatheightmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.SeatheightmmLabel_2.Text = 'Seat height [mm]';
            app.input_int_seat_height_2 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_height_2.Step = 25;
            app.input_int_seat_height_2.Limits = [5 800];
            app.input_int_seat_height_2.ValueChangedFcn = createCallbackFcn(app, @input_int_seat_height_2ValueChanged, true);
            app.input_int_seat_height_2.Value = 400;
            app.SeatadjustmentzmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.SeatadjustmentzmmLabel_2.Text = 'Seat adjustment z [mm]';
            app.input_int_seat_adjustment_z_2 = uispinner(app.InteriorPanel_3);
            app.input_int_seat_adjustment_z_2.Step = 10;
            app.input_int_seat_adjustment_z_2.Limits = [0 300];
            app.input_int_seat_adjustment_z_2.ValueChangedFcn = createCallbackFcn(app, @input_int_seat_adjustment_z_2ValueChanged, true);
            app.input_int_seat_adjustment_z_2.Value = 100;
            app.FootlengthmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.FootlengthmmLabel_2.Text = 'Foot length [mm]';
            app.input_int_foot_length_2 = uispinner(app.InteriorPanel_3);
            app.input_int_foot_length_2.Step = 5;
            app.input_int_foot_length_2.Limits = [0 350];
            app.input_int_foot_length_2.Value = 310;
            app.LegoverlapmmLabel_3 = uilabel(app.InteriorPanel_3);
            app.LegoverlapmmLabel_3.Text = 'Leg overlap [mm]';
            app.input_int_x_overlap = uispinner(app.InteriorPanel_3);
            app.input_int_x_overlap.Step = 10;
            app.input_int_x_overlap.Limits = [0 1000];
            app.input_int_x_overlap.Value = 100;
            app.LegroomadditionalmmLabel = uilabel(app.InteriorPanel_3);
            app.LegroomadditionalmmLabel.Text = 'Legroom additional [mm]';
            app.input_int_legroom_additional_1 = uispinner(app.InteriorPanel_3);
            app.input_int_legroom_additional_1.Step = 10;
            app.input_int_legroom_additional_1.Limits = [0 800];
            app.input_int_legroom_additional_1.Value = 200;
            app.LegroomadditionalmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.LegroomadditionalmmLabel_2.Text = 'Legroom additional [mm]';
            app.input_int_legroom_additional_2 = uispinner(app.InteriorPanel_3);
            app.input_int_legroom_additional_2.Step = 10;
            app.input_int_legroom_additional_2.Limits = [0 800];
            app.input_int_legroom_additional_2.Value = 200;
            app.NumberofdoorsLabel_3 = uilabel(app.InteriorPanel_3);
            app.NumberofdoorsLabel_3.Text = 'Number of doors';
            app.input_n_doors = uispinner(app.InteriorPanel_3);
            app.input_n_doors.Step = 2;
            app.input_n_doors.Limits = [2 4];
            app.input_n_doors.Value = 2;
            app.WalldistancemmLabel_3 = uilabel(app.InteriorPanel_3);
            app.WalldistancemmLabel_3.Enable = 'off';
            app.WalldistancemmLabel_3.Text = 'Wall distance [mm]';
            app.input_int_b2b_walldistance = uispinner(app.InteriorPanel_3);
            app.input_int_b2b_walldistance.Step = 10;
            app.input_int_b2b_walldistance.Limits = [0 1000];
            app.input_int_b2b_walldistance.Enable = 'off';
            app.input_int_b2b_walldistance.Value = 200;
            app.UseCaseLabel = uilabel(app.InteriorPanel_3);
            app.UseCaseLabel.Text = 'Use Case';
            app.input_int_usecase = uidropdown(app.InteriorPanel_3);
            app.input_int_usecase.Items = {'Individual', 'Comfort Class', 'Middle Class', 'Low Class', '', ''};
            app.input_int_usecase.ValueChangedFcn = createCallbackFcn(app, @input_int_usecaseValueChanged, true);
            app.input_int_usecase.Value = 'Individual';
            app.BackrestanglemindegLabel = uilabel(app.InteriorPanel_3);
            app.BackrestanglemindegLabel.Text = 'Backrest angle (min) [deg]';
            app.input_int_angle_br_min_1 = uispinner(app.InteriorPanel_3);
            app.input_int_angle_br_min_1.Step = 5;
            app.input_int_angle_br_min_1.Limits = [0 90];
            app.input_int_angle_br_min_1.ValueChangedFcn = createCallbackFcn(app, @input_int_angle_br_min_1ValueChanged, true);
            app.input_int_angle_br_min_1.Value = 70;
            app.BackrestanglemindegLabel_2 = uilabel(app.InteriorPanel_3);
            app.BackrestanglemindegLabel_2.Text = 'Backrest angle (min) [deg]';
            app.input_int_angle_br_min_2 = uispinner(app.InteriorPanel_3);
            app.input_int_angle_br_min_2.Step = 5;
            app.input_int_angle_br_min_2.Limits = [0 90];
            app.input_int_angle_br_min_2.ValueChangedFcn = createCallbackFcn(app, @input_int_angle_br_min_2ValueChanged, true);
            app.input_int_angle_br_min_2.Value = 70;
            app.BackrestwidthmmLabel = uilabel(app.InteriorPanel_3);
            app.BackrestwidthmmLabel.Text = 'Backrest width [mm]';
            app.input_int_backrest_width_1 = uispinner(app.InteriorPanel_3);
            app.input_int_backrest_width_1.Step = 10;
            app.input_int_backrest_width_1.Limits = [300 800];
            app.input_int_backrest_width_1.Value = 530;
            app.SinglearmrestwidthmmLabel = uilabel(app.InteriorPanel_3);
            app.SinglearmrestwidthmmLabel.Text = 'Single armrest width [mm]';
            app.input_int_armrest_width_single_1 = uispinner(app.InteriorPanel_3);
            app.input_int_armrest_width_single_1.Step = 10;
            app.input_int_armrest_width_single_1.Limits = [0 200];
            app.input_int_armrest_width_single_1.Value = 80;
            app.GapbetweenseatsmmLabel_5 = uilabel(app.InteriorPanel_3);
            app.GapbetweenseatsmmLabel_5.Text = 'Gap between seats [mm]';
            app.input_int_seatgap_1 = uispinner(app.InteriorPanel_3);
            app.input_int_seatgap_1.Step = 25;
            app.input_int_seatgap_1.Limits = [0 800];
            app.input_int_seatgap_1.Value = 40;
            app.BackrestthicknessmmLabel = uilabel(app.InteriorPanel_3);
            app.BackrestthicknessmmLabel.Text = 'Backrest thickness [mm]';
            app.input_int_thickness_br_1 = uispinner(app.InteriorPanel_3);
            app.input_int_thickness_br_1.Step = 10;
            app.input_int_thickness_br_1.Limits = [10 350];
            app.input_int_thickness_br_1.Value = 150;
            app.SeatthicknessmmLabel = uilabel(app.InteriorPanel_3);
            app.SeatthicknessmmLabel.Text = 'Seat thickness [mm]';
            app.input_int_thickness_seat_1 = uispinner(app.InteriorPanel_3);
            app.input_int_thickness_seat_1.Step = 10;
            app.input_int_thickness_seat_1.Limits = [10 350];
            app.input_int_thickness_seat_1.ValueChangedFcn = createCallbackFcn(app, @input_int_thickness_seat_1ValueChanged, true);
            app.input_int_thickness_seat_1.Value = 150;
            app.BackrestwidthmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.BackrestwidthmmLabel_2.Text = 'Backrest width [mm]';
            app.input_int_backrest_width_2 = uispinner(app.InteriorPanel_3);
            app.input_int_backrest_width_2.Step = 10;
            app.input_int_backrest_width_2.Limits = [300 800];
            app.input_int_backrest_width_2.Value = 530;
            app.SinglearmrestwidthmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.SinglearmrestwidthmmLabel_2.Text = 'Single armrest width [mm]';
            app.input_int_armrest_width_single_2 = uispinner(app.InteriorPanel_3);
            app.input_int_armrest_width_single_2.Step = 10;
            app.input_int_armrest_width_single_2.Limits = [0 200];
            app.input_int_armrest_width_single_2.Value = 80;
            app.GapbetweenseatsmmLabel_6 = uilabel(app.InteriorPanel_3);
            app.GapbetweenseatsmmLabel_6.Text = 'Gap between seats [mm]';
            app.input_int_seatgap_2 = uispinner(app.InteriorPanel_3);
            app.input_int_seatgap_2.Step = 25;
            app.input_int_seatgap_2.Limits = [0 800];
            app.input_int_seatgap_2.Value = 40;
            app.BackrestthicknessmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.BackrestthicknessmmLabel_2.Text = 'Backrest thickness [mm]';
            app.input_int_thickness_br_2 = uispinner(app.InteriorPanel_3);
            app.input_int_thickness_br_2.Step = 10;
            app.input_int_thickness_br_2.Limits = [10 350];
            app.input_int_thickness_br_2.Value = 150;
            app.SeatthicknessmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.SeatthicknessmmLabel_2.Text = 'Seat thickness [mm]';
            app.input_int_thickness_seat_2 = uispinner(app.InteriorPanel_3);
            app.input_int_thickness_seat_2.Step = 10;
            app.input_int_thickness_seat_2.Limits = [10 350];
            app.input_int_thickness_seat_2.ValueChangedFcn = createCallbackFcn(app, @input_int_thickness_seat_2ValueChanged, true);
            app.input_int_thickness_seat_2.Value = 150;
            app.input_int_thickness_seat_3 = uispinner(app.InteriorPanel_3);
            app.input_int_thickness_seat_3.Step = 10;
            app.input_int_thickness_seat_3.Limits = [10 350];
            app.input_int_thickness_seat_3.Value = 150;
            app.BackrestlengthmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.BackrestlengthmmLabel_2.Text = 'Backrest length [mm]';
            app.input_int_backrest_length_2 = uispinner(app.InteriorPanel_3);
            app.input_int_backrest_length_2.Step = 50;
            app.input_int_backrest_length_2.Limits = [0 1200];
            app.input_int_backrest_length_2.Value = 800;
            app.BackrestlengthmmLabel = uilabel(app.InteriorPanel_3);
            app.BackrestlengthmmLabel.Text = 'Backrest length [mm]';
            app.input_int_backrest_length_1 = uispinner(app.InteriorPanel_3);
            app.input_int_backrest_length_1.Step = 50;
            app.input_int_backrest_length_1.Limits = [0 1200];
            app.input_int_backrest_length_1.Value = 800;
            app.input_int_work = uicheckbox(app.InteriorPanel_3);
            app.input_int_work.ValueChangedFcn = createCallbackFcn(app, @input_int_workValueChanged, true);
            app.input_int_work.Text = 'Work Package';
            app.input_int_sleep = uicheckbox(app.InteriorPanel_3);
            app.input_int_sleep.ValueChangedFcn = createCallbackFcn(app, @input_int_sleepValueChanged, true);
            app.input_int_sleep.Text = 'Sleep Package';
            app.DoublearmrestwidthmmLabel = uilabel(app.InteriorPanel_3);
            app.DoublearmrestwidthmmLabel.Text = 'Double armrest width [mm]';
            app.input_int_armrest_width_double_1 = uispinner(app.InteriorPanel_3);
            app.input_int_armrest_width_double_1.Step = 10;
            app.input_int_armrest_width_double_1.Limits = [0 400];
            app.input_int_armrest_width_double_1.Value = 80;
            app.DoublearmrestwidthmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.DoublearmrestwidthmmLabel_2.Text = 'Double armrest width [mm]';
            app.input_int_armrest_width_double_2 = uispinner(app.InteriorPanel_3);
            app.input_int_armrest_width_double_2.Step = 10;
            app.input_int_armrest_width_double_2.Limits = [0 400];
            app.input_int_armrest_width_double_2.Value = 80;
            app.GaptowallmmLabel = uilabel(app.InteriorPanel_3);
            app.GaptowallmmLabel.Text = 'Gap to wall [mm]';
            app.input_int_gaptowall_1 = uispinner(app.InteriorPanel_3);
            app.input_int_gaptowall_1.Step = 25;
            app.input_int_gaptowall_1.Limits = [0 800];
            app.input_int_gaptowall_1.Value = 40;
            app.GaptowallmmLabel_2 = uilabel(app.InteriorPanel_3);
            app.GaptowallmmLabel_2.Text = 'Gap to wall [mm]';
            app.input_int_gaptowall_2 = uispinner(app.InteriorPanel_3);
            app.input_int_gaptowall_2.Step = 25;
            app.input_int_gaptowall_2.Limits = [0 800];
            app.input_int_gaptowall_2.Value = 40;
            app.input_int_single_ar_outside_2 = uicheckbox(app.InteriorPanel_3);
            app.input_int_single_ar_outside_2.Text = 'Single armrest outside';
            app.input_int_single_ar_between_2 = uicheckbox(app.InteriorPanel_3);
            app.input_int_single_ar_between_2.ValueChangedFcn = createCallbackFcn(app, @input_int_single_ar_between_2ValueChanged, true);
            app.input_int_single_ar_between_2.Text = 'Single armrest between seats';
            app.input_int_double_ar_2 = uicheckbox(app.InteriorPanel_3);
            app.input_int_double_ar_2.ValueChangedFcn = createCallbackFcn(app, @input_int_double_ar_2ValueChanged, true);
            app.input_int_double_ar_2.Text = 'Double armrest between seats';
            app.input_int_single_ar_outside_1 = uicheckbox(app.InteriorPanel_3);
            app.input_int_single_ar_outside_1.Text = 'Single armrest outside';
            app.input_int_single_ar_between_1 = uicheckbox(app.InteriorPanel_3);
            app.input_int_single_ar_between_1.ValueChangedFcn = createCallbackFcn(app, @input_int_single_ar_between_1ValueChanged, true);
            app.input_int_single_ar_between_1.Text = 'Single armrest between seats';
            app.input_int_double_ar_1 = uicheckbox(app.InteriorPanel_3);
            app.input_int_double_ar_1.ValueChangedFcn = createCallbackFcn(app, @input_int_double_ar_1ValueChanged, true);
            app.input_int_double_ar_1.Text = 'Double armrest between seats';
            app.input_H30Opt = uicheckbox(app.InteriorPanel_3);
            app.input_H30Opt.ValueChangedFcn = createCallbackFcn(app, @input_H30OptValueChanged, true);
            app.input_H30Opt.Text = 'H30 Optimization';
            app.ExtrasTab = uitab(app.TabGroup);
            app.ExtrasTab.Title = '4. Extras';
            app.SensorsPanel = uipanel(app.ExtrasTab);
            app.SensorsPanel.Title = 'Sensors';
            app.NumberofSonarsensorsSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofSonarsensorsSpinnerLabel.Text = 'Number of Sonar sensors';
            app.input_sonar = uispinner(app.SensorsPanel);
            app.input_sonar.Limits = [0 50];
            app.input_sonar.Value = 8;
            app.NumberofRadarsensorsSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofRadarsensorsSpinnerLabel.Text = 'Number of Radar sensors';
            app.input_radar = uispinner(app.SensorsPanel);
            app.input_radar.Limits = [0 30];
            app.input_radar.Value = 5;
            app.NumberofSurroundCamerasSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofSurroundCamerasSpinnerLabel.Text = 'Number of Surround Cameras';
            app.input_surround_cameras = uispinner(app.SensorsPanel);
            app.input_surround_cameras.Limits = [0 30];
            app.input_surround_cameras.Value = 4;
            app.NumberofFrontCamerasSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofFrontCamerasSpinnerLabel.Text = 'Number of Front Cameras';
            app.input_front_cameras = uispinner(app.SensorsPanel);
            app.input_front_cameras.Limits = [0 30];
            app.input_front_cameras.Value = 4;
            app.input_c2x = uicheckbox(app.SensorsPanel);
            app.input_c2x.Text = 'C2X Interface';
            app.input_c2x.Value = true;
            app.input_5g = uicheckbox(app.SensorsPanel);
            app.input_5g.Text = '5G compatibility';
            app.input_5g.Value = true;
            app.input_onboard_pc = uicheckbox(app.SensorsPanel);
            app.input_onboard_pc.Text = 'Onboard Computer';
            app.input_onboard_pc.Value = true;
            app.input_gps_reciever = uicheckbox(app.SensorsPanel);
            app.input_gps_reciever.Text = 'GPS reciever';
            app.input_gps_reciever.Value = true;
            app.input_gps_correction = uicheckbox(app.SensorsPanel);
            app.input_gps_correction.Text = 'GPS correction service';
            app.NumberofmechLIDARsensorsLabel = uilabel(app.SensorsPanel);
            app.NumberofmechLIDARsensorsLabel.Text = 'Number of LIDAR sensors high';
            app.input_mech_lidar = uispinner(app.SensorsPanel);
            app.input_mech_lidar.Limits = [0 20];
            app.input_mech_lidar.Value = 2;
            app.NumberofmemsLIDARsensorsSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofmemsLIDARsensorsSpinnerLabel.Text = 'Number of LIDAR sensors low';
            app.input_mems_lidar = uispinner(app.SensorsPanel);
            app.input_mems_lidar.Limits = [0 20];
            app.InteriorPanel_2 = uipanel(app.ExtrasTab);
            app.InteriorPanel_2.Title = 'Interior';
            app.NumberofsideairbagsLabel = uilabel(app.InteriorPanel_2);
            app.NumberofsideairbagsLabel.Text = 'Number of side airbags';
            app.input_side_airbags = uispinner(app.InteriorPanel_2);
            app.input_side_airbags.Limits = [0 10];
            app.input_side_airbags.Value = 2;
            app.input_hvac = uicheckbox(app.InteriorPanel_2);
            app.input_hvac.Text = 'HVAC';
            app.input_hvac.Value = true;
            app.input_seat_warmers = uicheckbox(app.InteriorPanel_2);
            app.input_seat_warmers.Text = 'Seat warmers';
            app.input_seat_warmers.Value = true;
            app.input_pneumatic_doors = uicheckbox(app.InteriorPanel_2);
            app.input_pneumatic_doors.Text = 'Pneumatic doors';
            app.input_window_lift = uicheckbox(app.InteriorPanel_2);
            app.input_window_lift.Text = 'Electronic side window lift';
            app.input_window_lift.Value = true;
            app.NumberofinteriordisplaysLabel = uilabel(app.InteriorPanel_2);
            app.NumberofinteriordisplaysLabel.Text = 'Number of interior displays';
            app.input_displays = uispinner(app.InteriorPanel_2);
            app.input_displays.Limits = [0 10];
            app.input_displays.Value = 2;
            app.input_adjustable_seats = uicheckbox(app.InteriorPanel_2);
            app.input_adjustable_seats.Text = 'Automatic adjustable seats';
            app.input_adjustable_seats.Value = true;
            app.input_panorama_roof = uicheckbox(app.InteriorPanel_2);
            app.input_panorama_roof.Text = 'Panorama roof';
            app.input_sliding_roof = uicheckbox(app.InteriorPanel_2);
            app.input_sliding_roof.Text = 'Sliding roof';
            app.ManufacturingandmaterialsPanel = uipanel(app.ExtrasTab);
            app.ManufacturingandmaterialsPanel.Title = 'Manufacturing and materials';
            app.EstimatedassemblytimehLabel = uilabel(app.ManufacturingandmaterialsPanel);
            app.EstimatedassemblytimehLabel.Text = 'Estimated assembly time [h]';
            app.input_assembly_time = uispinner(app.ManufacturingandmaterialsPanel);
            app.input_assembly_time.Step = 2;
            app.input_assembly_time.Limits = [0 100];
            app.input_assembly_time.Value = 25;
            app.ClosuresmaterialDropDownLabel = uilabel(app.ManufacturingandmaterialsPanel);
            app.ClosuresmaterialDropDownLabel.Text = 'Closures material';
            app.input_closures_material = uidropdown(app.ManufacturingandmaterialsPanel);
            app.input_closures_material.Items = {'Aluminium', 'Steel'};
            app.input_closures_material.Value = 'Aluminium';
            app.AluminiumpercentageBIWLabel = uilabel(app.ManufacturingandmaterialsPanel);
            app.AluminiumpercentageBIWLabel.Text = 'Aluminium percentage BIW [%]';
            app.input_biw_alu_perc = uispinner(app.ManufacturingandmaterialsPanel);
            app.input_biw_alu_perc.Step = 5;
            app.input_biw_alu_perc.Limits = [0 100];
            app.input_biw_alu_perc.Value = 100;
            app.DoormaterialLabel = uilabel(app.ManufacturingandmaterialsPanel);
            app.DoormaterialLabel.Text = 'Door material';
            app.input_door_material = uidropdown(app.ManufacturingandmaterialsPanel);
            app.input_door_material.Items = {'Aluminium', 'Steel'};
            app.input_door_material.Value = 'Aluminium';
            app.TailgatematerialLabel = uilabel(app.ManufacturingandmaterialsPanel);
            app.TailgatematerialLabel.Text = 'Tailgate material';
            app.input_tailgate_material = uidropdown(app.ManufacturingandmaterialsPanel);
            app.input_tailgate_material.Items = {'Aluminium', 'Steel'};
            app.input_tailgate_material.Value = 'Aluminium';
            app.HoodmaterialLabel = uilabel(app.ManufacturingandmaterialsPanel);
            app.HoodmaterialLabel.Text = 'Hood material';
            app.input_hood_material = uidropdown(app.ManufacturingandmaterialsPanel);
            app.input_hood_material.Items = {'Aluminium', 'Steel'};
            app.input_hood_material.Value = 'Aluminium';
            app.FendersmaterialLabel = uilabel(app.ManufacturingandmaterialsPanel);
            app.FendersmaterialLabel.Text = 'Fenders material';
            app.input_fenders_material = uidropdown(app.ManufacturingandmaterialsPanel);
            app.input_fenders_material.Items = {'Aluminium', 'Steel'};
            app.input_fenders_material.Value = 'Aluminium';
            app.input_spare_tire = uicheckbox(app.ManufacturingandmaterialsPanel);
            app.input_spare_tire.Text = 'Spare tire';
            app.PowertrainPanel = uipanel(app.ExtrasTab);
            app.PowertrainPanel.Title = 'Powertrain';
            app.input_sbw = uicheckbox(app.PowertrainPanel);
            app.input_sbw.Text = 'Steer-by-Wire';
            app.input_sbw.Value = true;
            app.input_emech_steering = uicheckbox(app.PowertrainPanel);
            app.input_emech_steering.Text = 'Electromech. Steering';
            app.input_emech_steering.Value = true;
            app.input_emech_brakes = uicheckbox(app.PowertrainPanel);
            app.input_emech_brakes.Text = 'Electromech. Braking';
            app.input_emech_brakes.Value = true;
            app.input_obc = uicheckbox(app.PowertrainPanel);
            app.input_obc.Text = 'On-board-Charger';
            app.input_obc.Value = true;
            app.AdditionalFeaturesPanel = uipanel(app.ExtrasTab);
            app.AdditionalFeaturesPanel.Title = 'Additional Features';
            app.input_keyless_go = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_keyless_go.Text = 'Keyless go';
            app.input_keyless_go.Value = true;
            app.input_acc = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_acc.Text = 'ACC';
            app.input_acc.Value = true;
            app.input_lks = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_lks.Text = 'LKS';
            app.input_lks.Value = true;
            app.input_bsm = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_bsm.Text = 'BSM';
            app.input_bsm.Value = true;
            app.input_park_assist = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_park_assist.Text = 'Park assist';
            app.input_park_assist.Value = true;
            app.input_night_vision = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_night_vision.Text = 'Night Vision';
            app.input_trunk_assist = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_trunk_assist.Text = 'Trunk assist';
            app.input_trunk_assist.Value = true;
            app.input_phone_connectivity = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_phone_connectivity.Text = 'Phone connectivity';
            app.input_phone_connectivity.Value = true;
            app.HeadlighttechnologyLabel = uilabel(app.AdditionalFeaturesPanel);
            app.HeadlighttechnologyLabel.Text = 'Headlight technology';
            app.input_headlight_tech = uidropdown(app.AdditionalFeaturesPanel);
            app.input_headlight_tech.Items = {'LED', 'Xenon', 'Halogen'};
            app.input_headlight_tech.Value = 'LED';
            app.BatteryPanel_2 = uipanel(app.ExtrasTab);
            app.BatteryPanel_2.Title = 'Battery Settings';
            app.input_batterycooling_in_z = uicheckbox(app.BatteryPanel_2);
            app.input_batterycooling_in_z.Text = 'Cooling plate under battery';
            app.input_batterycooling_in_z.Value = true;
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = '5. Optimization';
            app.EnginePanel = uipanel(app.OptimizationTab);
            app.EnginePanel.Title = 'Engine';
            app.DrivetypeLabel = uilabel(app.EnginePanel);
            app.DrivetypeLabel.Text = 'Drive type';
            app.input_drive_type = uidropdown(app.EnginePanel);
            app.input_drive_type.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type.ValueChangedFcn = createCallbackFcn(app, @input_drive_typeValueChanged, true);
            app.input_drive_type.Value = 'All Combinations';
            app.EnginetypeLabel = uilabel(app.EnginePanel);
            app.EnginetypeLabel.Text = 'Engine type';
            app.input_engine_type = uidropdown(app.EnginePanel);
            app.input_engine_type.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type.Value = 'All Combinations';
            app.MinengineanglefrontdegLabel = uilabel(app.EnginePanel);
            app.MinengineanglefrontdegLabel.Text = 'Min. engine angle front  [deg]';
            app.input_engine_angle_front_min = uispinner(app.EnginePanel);
            app.input_engine_angle_front_min.Step = 5;
            app.input_engine_angle_front_min.Limits = [0 180];
            app.input_engine_angle_front_min.ValueChangedFcn = createCallbackFcn(app, @input_engine_angle_front_minValueChanged, true);
            app.MaxengineanglefrontdegLabel = uilabel(app.EnginePanel);
            app.MaxengineanglefrontdegLabel.Text = 'Max. engine angle front [deg]';
            app.input_engine_angle_front_max = uispinner(app.EnginePanel);
            app.input_engine_angle_front_max.Step = 5;
            app.input_engine_angle_front_max.Limits = [0 180];
            app.input_engine_angle_front_max.Value = 180;
            app.MinengineanglereardegLabel = uilabel(app.EnginePanel);
            app.MinengineanglereardegLabel.Text = 'Min. engine angle rear  [deg]';
            app.input_engine_angle_rear_min = uispinner(app.EnginePanel);
            app.input_engine_angle_rear_min.Step = 5;
            app.input_engine_angle_rear_min.Limits = [0 180];
            app.input_engine_angle_rear_min.ValueChangedFcn = createCallbackFcn(app, @input_engine_angle_rear_minValueChanged, true);
            app.MaxengineanglereardegLabel = uilabel(app.EnginePanel);
            app.MaxengineanglereardegLabel.Text = 'Max. engine angle rear [deg]';
            app.input_engine_angle_rear_max = uispinner(app.EnginePanel);
            app.input_engine_angle_rear_max.Step = 5;
            app.input_engine_angle_rear_max.Limits = [0 180];
            app.input_engine_angle_rear_max.Value = 180;
            app.MintorqueratiofrontLabel = uilabel(app.EnginePanel);
            app.MintorqueratiofrontLabel.Text = 'Min. torque ratio front [%]';
            app.input_torque_ratio_min = uispinner(app.EnginePanel);
            app.input_torque_ratio_min.Step = 5;
            app.input_torque_ratio_min.Limits = [5 95];
            app.input_torque_ratio_min.Value = 5;
            app.MaxtorqueratiofrontLabel = uilabel(app.EnginePanel);
            app.MaxtorqueratiofrontLabel.Text = 'Max. torque ratio front [%]';
            app.input_torque_ratio_max = uispinner(app.EnginePanel);
            app.input_torque_ratio_max.Step = 5;
            app.input_torque_ratio_max.Limits = [5 95];
            app.input_torque_ratio_max.Value = 95;
            app.AxlePanel = uipanel(app.OptimizationTab);
            app.AxlePanel.Title = 'Axle';
            app.MinaxleanglexydegLabel = uilabel(app.AxlePanel);
            app.MinaxleanglexydegLabel.Text = 'Min. axle angle (xy) [deg]';
            app.input_axle_angle_1_min = uispinner(app.AxlePanel);
            app.input_axle_angle_1_min.Step = 0.5;
            app.input_axle_angle_1_min.Limits = [0 13];
            app.input_axle_angle_1_min.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_1_min.ValueChangedFcn = createCallbackFcn(app, @input_axle_angle_1_minValueChanged, true);
            app.input_axle_angle_1_min.Value = 1;
            app.MaxaxleanglexydegLabel = uilabel(app.AxlePanel);
            app.MaxaxleanglexydegLabel.Text = 'Max. axle angle (xy) [deg]';
            app.input_axle_angle_1_max = uispinner(app.AxlePanel);
            app.input_axle_angle_1_max.Step = 0.5;
            app.input_axle_angle_1_max.Limits = [0 13];
            app.input_axle_angle_1_max.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_1_max.Value = 13;
            app.MinaxleanglexzdegLabel = uilabel(app.AxlePanel);
            app.MinaxleanglexzdegLabel.Text = 'Min. axle angle (xz) [deg]';
            app.input_axle_angle_2_min = uispinner(app.AxlePanel);
            app.input_axle_angle_2_min.Step = 0.5;
            app.input_axle_angle_2_min.Limits = [0 13];
            app.input_axle_angle_2_min.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_2_min.ValueChangedFcn = createCallbackFcn(app, @input_axle_angle_2_minValueChanged, true);
            app.input_axle_angle_2_min.Value = 1;
            app.MaxaxleanglexzdegLabel = uilabel(app.AxlePanel);
            app.MaxaxleanglexzdegLabel.Text = 'Max. axle angle (xz) [deg]';
            app.input_axle_angle_2_max = uispinner(app.AxlePanel);
            app.input_axle_angle_2_max.Step = 0.5;
            app.input_axle_angle_2_max.Limits = [0 13];
            app.input_axle_angle_2_max.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_2_max.Value = 13;
            app.RearaxletypeLabel = uilabel(app.AxlePanel);
            app.RearaxletypeLabel.Text = 'Rear axle type';
            app.input_rear_axis_type = uidropdown(app.AxlePanel);
            app.input_rear_axis_type.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type.Value = 'All Combinations';
            app.GearboxPanel = uipanel(app.OptimizationTab);
            app.GearboxPanel.Title = 'Gearbox';
            app.GeartypeRearLabel = uilabel(app.GearboxPanel);
            app.GeartypeRearLabel.Text = 'Gear type Rear';
            app.input_gear_type_rear = uidropdown(app.GearboxPanel);
            app.input_gear_type_rear.Items = {'Coaxial', 'Parallel', 'Coaxial-layshaft', 'All Combinations'};
            app.input_gear_type_rear.ValueChangedFcn = createCallbackFcn(app, @input_gear_type_rearValueChanged, true);
            app.input_gear_type_rear.Value = 'All Combinations';
            app.GeartypeFrontLabel = uilabel(app.GearboxPanel);
            app.GeartypeFrontLabel.Text = 'Gear type Front';
            app.input_gear_type_front = uidropdown(app.GearboxPanel);
            app.input_gear_type_front.Items = {'Coaxial', 'Parallel', 'Coaxial-layshaft', 'All Combinations'};
            app.input_gear_type_front.ValueChangedFcn = createCallbackFcn(app, @input_gear_type_frontValueChanged, true);
            app.input_gear_type_front.Value = 'All Combinations';
            app.Mingearratio1frontLabel = uilabel(app.GearboxPanel);
            app.Mingearratio1frontLabel.Text = 'Min. gear ratio 1 front';
            app.input_gear_ratio_1_front_min = uispinner(app.GearboxPanel);
            app.input_gear_ratio_1_front_min.Step = 0.2;
            app.input_gear_ratio_1_front_min.Limits = [6 12];
            app.input_gear_ratio_1_front_min.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_front_min.ValueChangedFcn = createCallbackFcn(app, @input_gear_ratio_1_front_minValueChanged, true);
            app.input_gear_ratio_1_front_min.Value = 6;
            app.Maxgearratio1frontLabel = uilabel(app.GearboxPanel);
            app.Maxgearratio1frontLabel.Text = 'Max. gear ratio 1 front';
            app.input_gear_ratio_1_front_max = uispinner(app.GearboxPanel);
            app.input_gear_ratio_1_front_max.Step = 0.2;
            app.input_gear_ratio_1_front_max.Limits = [0 12];
            app.input_gear_ratio_1_front_max.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_front_max.Value = 10;
            app.Mingearratio1rearLabel = uilabel(app.GearboxPanel);
            app.Mingearratio1rearLabel.Text = 'Min. gear ratio 1 rear';
            app.input_gear_ratio_1_rear_min = uispinner(app.GearboxPanel);
            app.input_gear_ratio_1_rear_min.Step = 0.2;
            app.input_gear_ratio_1_rear_min.Limits = [6 12];
            app.input_gear_ratio_1_rear_min.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_rear_min.ValueChangedFcn = createCallbackFcn(app, @input_gear_ratio_1_rear_minValueChanged, true);
            app.input_gear_ratio_1_rear_min.Value = 6;
            app.Maxgearratio1rearLabel = uilabel(app.GearboxPanel);
            app.Maxgearratio1rearLabel.Text = 'Max. gear ratio 1 rear';
            app.input_gear_ratio_1_rear_max = uispinner(app.GearboxPanel);
            app.input_gear_ratio_1_rear_max.Step = 0.2;
            app.input_gear_ratio_1_rear_max.Limits = [0 12];
            app.input_gear_ratio_1_rear_max.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_rear_max.Value = 10;
            app.GearboxoptgoalLabel = uilabel(app.GearboxPanel);
            app.GearboxoptgoalLabel.Text = 'Gearbox opt. goal';
            app.input_gearbox_opt = uidropdown(app.GearboxPanel);
            app.input_gearbox_opt.Items = {'Length', 'Height', 'All Combinations'};
            app.input_gearbox_opt.Value = 'All Combinations';
            app.OthersPanel = uipanel(app.OptimizationTab);
            app.OthersPanel.Title = 'Others';
            app.MintirediametermmLabel = uilabel(app.OthersPanel);
            app.MintirediametermmLabel.Text = 'Min. tire diameter [mm]';
            app.input_tire_diam_min = uispinner(app.OthersPanel);
            app.input_tire_diam_min.Step = 20;
            app.input_tire_diam_min.Limits = [600 900];
            app.input_tire_diam_min.ValueChangedFcn = createCallbackFcn(app, @input_tire_diam_minValueChanged, true);
            app.input_tire_diam_min.Value = 600;
            app.MaxtirediametermmLabel = uilabel(app.OthersPanel);
            app.MaxtirediametermmLabel.Text = 'Max. tire diameter [mm]';
            app.input_tire_diam_max = uispinner(app.OthersPanel);
            app.input_tire_diam_max.Step = 20;
            app.input_tire_diam_max.Limits = [600 800];
            app.input_tire_diam_max.Value = 800;
            app.MinsteeringdistributionfrontLabel = uilabel(app.OthersPanel);
            app.MinsteeringdistributionfrontLabel.Text = {'Min. steering distribution'; 'front [%]'};
            app.input_steering_ratio_min = uispinner(app.OthersPanel);
            app.input_steering_ratio_min.Step = 2;
            app.input_steering_ratio_min.Limits = [50 100];
            app.input_steering_ratio_min.Value = 50;
            app.MaxsteeringdistributionfrontLabel = uilabel(app.OthersPanel);
            app.MaxsteeringdistributionfrontLabel.Text = {'Max. steering distribution'; 'front [%]'};
            app.input_steering_ratio_max = uispinner(app.OthersPanel);
            app.input_steering_ratio_max.Step = 2;
            app.input_steering_ratio_max.Limits = [50 100];
            app.input_steering_ratio_max.Value = 100;
            app.InteriorPanel_4 = uipanel(app.OptimizationTab);
            app.InteriorPanel_4.Title = 'Interior';
            app.MinH30frontLabel = uilabel(app.InteriorPanel_4);
            app.MinH30frontLabel.Text = 'Min. H30 front';
            app.input_minH30_front = uispinner(app.InteriorPanel_4);
            app.input_minH30_front.Step = 20;
            app.input_minH30_front.ValueChangingFcn = createCallbackFcn(app, @input_minH30_frontValueChanging, true);
            app.input_minH30_front.Limits = [100 800];
            app.input_minH30_front.Value = 300;
            app.MaxH30frontLabel = uilabel(app.InteriorPanel_4);
            app.MaxH30frontLabel.Text = 'Max. H30 front';
            app.input_maxH30_front = uispinner(app.InteriorPanel_4);
            app.input_maxH30_front.Step = 20;
            app.input_maxH30_front.ValueChangingFcn = createCallbackFcn(app, @input_maxH30_frontValueChanging, true);
            app.input_maxH30_front.Limits = [100 800];
            app.input_maxH30_front.Value = 400;
            app.MinH30rearLabel = uilabel(app.InteriorPanel_4);
            app.MinH30rearLabel.Text = 'Min. H30 rear';
            app.input_minH30_rear = uispinner(app.InteriorPanel_4);
            app.input_minH30_rear.Step = 20;
            app.input_minH30_rear.ValueChangingFcn = createCallbackFcn(app, @input_minH30_rearValueChanging, true);
            app.input_minH30_rear.Limits = [100 800];
            app.input_minH30_rear.Value = 300;
            app.MaxH30rearLabel = uilabel(app.InteriorPanel_4);
            app.MaxH30rearLabel.Text = 'Max. H30 rear';
            app.input_maxH30_rear = uispinner(app.InteriorPanel_4);
            app.input_maxH30_rear.Step = 20;
            app.input_maxH30_rear.ValueChangingFcn = createCallbackFcn(app, @input_maxH30_rearValueChanging, true);
            app.input_maxH30_rear.Limits = [100 800];
            app.input_maxH30_rear.Value = 400;
            app.MininteriorheightmmLabel = uilabel(app.InteriorPanel_4);
            app.MininteriorheightmmLabel.Text = 'Min. interior height [mm]';
            app.input_int_height_min = uispinner(app.InteriorPanel_4);
            app.input_int_height_min.Step = 20;
            app.input_int_height_min.Limits = [200 700];
            app.input_int_height_min.ValueChangedFcn = createCallbackFcn(app, @input_int_height_minValueChanged, true);
            app.input_int_height_min.Value = 300;
            app.MintrunkratiofrontrearLabel = uilabel(app.InteriorPanel_4);
            app.MintrunkratiofrontrearLabel.Text = 'Min. trunk ratio front [%]';
            app.input_trunk_ratio_min = uispinner(app.InteriorPanel_4);
            app.input_trunk_ratio_min.Step = 5;
            app.input_trunk_ratio_min.Limits = [0 100];
            app.input_trunk_ratio_min.ValueChangedFcn = createCallbackFcn(app, @input_trunk_ratio_minValueChanged, true);
            app.input_trunk_ratio_min.Value = 30;
            app.MaxtrunkratiofrontrearLabel = uilabel(app.InteriorPanel_4);
            app.MaxtrunkratiofrontrearLabel.Text = 'Max. trunk ratio front [%]';
            app.input_trunk_ratio_max = uispinner(app.InteriorPanel_4);
            app.input_trunk_ratio_max.Step = 5;
            app.input_trunk_ratio_max.Limits = [0 100];
            app.input_trunk_ratio_max.Value = 70;
            app.MaxinteriorheightmmLabel = uilabel(app.InteriorPanel_4);
            app.MaxinteriorheightmmLabel.Text = 'Max. interior height [mm]';
            app.input_int_height_max = uispinner(app.InteriorPanel_4);
            app.input_int_height_max.Step = 20;
            app.input_int_height_max.Limits = [300 700];
            app.input_int_height_max.Value = 600;
            app.BatteryPanel = uipanel(app.OptimizationTab);
            app.BatteryPanel.Title = 'Battery';
            app.CelltypeLabel = uilabel(app.BatteryPanel);
            app.CelltypeLabel.Text = 'Cell type';
            app.input_cell_type = uidropdown(app.BatteryPanel);
            app.input_cell_type.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type.Value = 'All Combinations';
            app.SmalloverlapLabel = uilabel(app.BatteryPanel);
            app.SmalloverlapLabel.Text = 'Small overlap';
            app.input_small_overlap = uidropdown(app.BatteryPanel);
            app.input_small_overlap.Items = {'Yes', 'No', 'All Combinations'};
            app.input_small_overlap.Value = 'All Combinations';
            app.ExteriorPanel = uipanel(app.OptimizationTab);
            app.ExteriorPanel.Title = 'Exterior';
            app.MinfrontwindowangleLabel = uilabel(app.ExteriorPanel);
            app.MinfrontwindowangleLabel.Text = 'Min. front window angle [°]';
            app.input_window_angle_front_min = uispinner(app.ExteriorPanel);
            app.input_window_angle_front_min.Limits = [0 85];
            app.input_window_angle_front_min.Value = 20;
            app.MinrearwindowangleLabel = uilabel(app.ExteriorPanel);
            app.MinrearwindowangleLabel.Text = 'Min. rear window angle [°]';
            app.input_window_angle_rear_min = uispinner(app.ExteriorPanel);
            app.input_window_angle_rear_min.Limits = [0 85];
            app.input_window_angle_rear_min.Value = 20;
            app.FronttrunkDropDownLabel = uilabel(app.ExteriorPanel);
            app.FronttrunkDropDownLabel.Text = 'Front trunk';
            app.input_trunk_type_front = uidropdown(app.ExteriorPanel);
            app.input_trunk_type_front.Items = {'integrated', 'hooded', 'All Combinations'};
            app.input_trunk_type_front.Value = 'All Combinations';
            app.ReartrunkDropDownLabel = uilabel(app.ExteriorPanel);
            app.ReartrunkDropDownLabel.Text = 'Rear trunk';
            app.input_trunk_type_rear = uidropdown(app.ExteriorPanel);
            app.input_trunk_type_rear.Items = {'integrated', 'hooded', 'All Combinations'};
            app.input_trunk_type_rear.Value = 'All Combinations';
            app.MaxfrontwindowangleLabel = uilabel(app.ExteriorPanel);
            app.MaxfrontwindowangleLabel.Text = 'Max. front window angle [°]';
            app.input_window_angle_front_max = uispinner(app.ExteriorPanel);
            app.input_window_angle_front_max.Limits = [0 85];
            app.input_window_angle_front_max.Value = 50;
            app.MaxrearwindowangleLabel = uilabel(app.ExteriorPanel);
            app.MaxrearwindowangleLabel.Text = 'Max. rear window angle [°]';
            app.input_window_angle_rear_max = uispinner(app.ExteriorPanel);
            app.input_window_angle_rear_max.Limits = [0 85];
            app.input_window_angle_rear_max.Value = 50;
            app.input_dist_wheelhouse2bumper_r_min = uispinner(app.ExteriorPanel);
            app.input_dist_wheelhouse2bumper_r_min.Step = 50;
            app.input_dist_wheelhouse2bumper_r_min.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_r_max = uispinner(app.ExteriorPanel);
            app.input_dist_wheelhouse2bumper_r_max.Step = 50;
            app.input_dist_wheelhouse2bumper_r_max.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_r_max.Value = 500;
            app.FrontLabel = uilabel(app.ExteriorPanel);
            app.FrontLabel.Text = 'Front';
            app.RearLabel = uilabel(app.ExteriorPanel);
            app.RearLabel.Text = 'Rear';
            app.MindistancewheelhousebumpermmLabel = uilabel(app.ExteriorPanel);
            app.MindistancewheelhousebumpermmLabel.Text = 'Min. distance wheelhouse-bumper [mm]';
            app.input_dist_wheelhouse2bumper_f_min = uispinner(app.ExteriorPanel);
            app.input_dist_wheelhouse2bumper_f_min.Step = 50;
            app.input_dist_wheelhouse2bumper_f_min.Limits = [0 800];
            app.MaxdistancewheelhousebumpermmLabel = uilabel(app.ExteriorPanel);
            app.MaxdistancewheelhousebumpermmLabel.Text = 'Max. distance wheelhouse-bumper [mm]';
            app.input_dist_wheelhouse2bumper_f_max = uispinner(app.ExteriorPanel);
            app.input_dist_wheelhouse2bumper_f_max.Step = 50;
            app.input_dist_wheelhouse2bumper_f_max.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_f_max.Value = 500;
            app.MindistanceinteriortofrontwagonmmLabel = uilabel(app.ExteriorPanel);
            app.MindistanceinteriortofrontwagonmmLabel.Text = 'Min. distance interior to front wagon [mm]';
            app.input_dist_int2fw_min = uispinner(app.ExteriorPanel);
            app.input_dist_int2fw_min.Step = 10;
            app.input_dist_int2fw_min.Limits = [0 500];
            app.FrontLabel_2 = uilabel(app.ExteriorPanel);
            app.FrontLabel_2.Text = 'Front';
            app.input_dist_int2rw_min = uispinner(app.ExteriorPanel);
            app.input_dist_int2rw_min.Step = 10;
            app.input_dist_int2rw_min.Limits = [0 500];
            app.input_dist_int2rw_max = uispinner(app.ExteriorPanel);
            app.input_dist_int2rw_max.Step = 10;
            app.input_dist_int2rw_max.Limits = [0 500];
            app.input_dist_int2rw_max.Value = 50;
            app.RearLabel_2 = uilabel(app.ExteriorPanel);
            app.RearLabel_2.Text = 'Rear';
            app.MaxdistanceinteriortofrontwagonmmLabel = uilabel(app.ExteriorPanel);
            app.MaxdistanceinteriortofrontwagonmmLabel.Text = 'Max. distance interior to front wagon [mm]';
            app.input_dist_int2fw_max = uispinner(app.ExteriorPanel);
            app.input_dist_int2fw_max.Step = 10;
            app.input_dist_int2fw_max.Limits = [0 500];
            app.input_dist_int2fw_max.Value = 50;
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = '6. Results';
            app.Output_Text_2 = uilabel(app.ResultsTab);
            app.Output_Text_2.Text = '';
            app.Output_Label = uilabel(app.GUIFahrzeugkonzepteUIFigure);
            app.Output_Label.Visible = 'off';
            app.Output_Label.Text = {'Label'; ''; ''; ''; ''; ''; ''; ''};
            app.Error_Label = uilabel(app.GUIFahrzeugkonzepteUIFigure);
            app.Error_Label.Visible = 'off';
            app.Error_Label.Text = {'Vehicle concept not feasible.'; 'Reason:'; ''; ''; ''; ''; ''; ''; 'Please adjust parameters and try again'};
            app.SAVECONFIGURATIONButton = uibutton(app.GUIFahrzeugkonzepteUIFigure, 'push');
            app.SAVECONFIGURATIONButton.ButtonPushedFcn = createCallbackFcn(app, @SAVECONFIGURATIONButtonPushed, true);
            app.SAVECONFIGURATIONButton.Text = 'SAVE CONFIGURATION';
            app.LOADCONFIGURATIONButton = uibutton(app.GUIFahrzeugkonzepteUIFigure, 'push');
            app.LOADCONFIGURATIONButton.ButtonPushedFcn = createCallbackFcn(app, @LOADCONFIGURATIONButtonPushed, true);
            app.LOADCONFIGURATIONButton.Text = 'LOAD CONFIGURATION';
            app.AutonomousVehicleConceptDevelopmentToolLabel = uilabel(app.GUIFahrzeugkonzepteUIFigure);
            app.AutonomousVehicleConceptDevelopmentToolLabel.Text = 'Autonomous Vehicle Concept Development Tool';
            app.CalculationandPlotSettingsPanel = uipanel(app.GUIFahrzeugkonzepteUIFigure);
            app.CalculationandPlotSettingsPanel.Title = 'Calculation and Plot Settings';
            app.VisualizationPanel = uipanel(app.CalculationandPlotSettingsPanel);
            app.VisualizationPanel.Title = 'Visualization';
            app.plot_battery = uicheckbox(app.VisualizationPanel);
            app.plot_battery.Text = 'Plot battery';
            app.plot_manikin = uicheckbox(app.VisualizationPanel);
            app.plot_manikin.Text = 'Plot manikins';
            app.plot_boundary = uicheckbox(app.VisualizationPanel);
            app.plot_boundary.Text = 'Plot boundaries';
            app.plot_wheel_env = uicheckbox(app.VisualizationPanel);
            app.plot_wheel_env.Text = 'Plot wheel envelope';
            app.OptimizationPanel = uipanel(app.CalculationandPlotSettingsPanel);
            app.OptimizationPanel.Title = 'Optimization';
            app.sing_veh = uicheckbox(app.OptimizationPanel);
            app.sing_veh.ValueChangedFcn = createCallbackFcn(app, @sing_vehValueChanged, true);
            app.sing_veh.Text = 'Single vehicle';
            app.PopulationSizeLabel = uilabel(app.OptimizationPanel);
            app.PopulationSizeLabel.Text = 'Pop. size';
            app.input_pop_size = uispinner(app.OptimizationPanel);
            app.input_pop_size.Step = 25;
            app.input_pop_size.Limits = [5 10000];
            app.input_pop_size.Value = 300;
            app.MaxgenerationLabel = uilabel(app.OptimizationPanel);
            app.MaxgenerationLabel.Text = 'Max. generation';
            app.input_max_gen = uispinner(app.OptimizationPanel);
            app.input_max_gen.Step = 5;
            app.input_max_gen.Limits = [10 1000];
            app.input_max_gen.Value = 30;
            app.input_parallel_comp = uicheckbox(app.OptimizationPanel);
            app.input_parallel_comp.Text = 'Parallel computing';
            app.TimeframeDropDownLabel = uilabel(app.OptimizationPanel);
            app.TimeframeDropDownLabel.Text = 'Timeframe';
            app.input_timeframe = uidropdown(app.OptimizationPanel);
            app.input_timeframe.Items = {'2020', '2025', '2030'};
            app.input_timeframe.Value = '2020';
            app.Goal1Label = uilabel(app.OptimizationPanel);
            app.Goal1Label.Text = ' Goal 1';
            app.input_goal_1 = uidropdown(app.OptimizationPanel);
            app.input_goal_1.Items = {'Min. Costs', 'Min. Battery Capacity', 'Min. Consumption', 'Min. Weight', 'Max. Range'};
            app.input_goal_1.ValueChangedFcn = createCallbackFcn(app, @input_goal_1ValueChanged, true);
            app.input_goal_1.Value = 'Min. Battery Capacity';
            app.Goal2Label = uilabel(app.OptimizationPanel);
            app.Goal2Label.Text = 'Goal 2';
            app.input_goal_2 = uidropdown(app.OptimizationPanel);
            app.input_goal_2.Items = {'Min. Costs', 'Min. Battery Capacity', 'Min. Consumption', 'Min. Weight', 'Max. Range'};
            app.input_goal_2.Value = 'Min. Costs';
            app.input_scale_axis = uicheckbox(app.OptimizationPanel);
            app.input_scale_axis.Text = 'Scale axis';
            app.input_showwarning = uicheckbox(app.CalculationandPlotSettingsPanel);
            app.input_showwarning.Text = 'Show warnings';
            app.OptimizationAlgorithmPanel = uipanel(app.CalculationandPlotSettingsPanel);
            app.SAVEOPTIMIZATIONPARAMETERSButton = uibutton(app.OptimizationAlgorithmPanel, 'push');
            app.SAVEOPTIMIZATIONPARAMETERSButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEOPTIMIZATIONPARAMETERSButtonPushed, true);
            app.SAVEOPTIMIZATIONPARAMETERSButton.Enable = 'off';
            app.SAVEOPTIMIZATIONPARAMETERSButton.Visible = 'off';
            app.SAVEOPTIMIZATIONPARAMETERSButton.Text = 'SAVE OPTIMIZATION PARAMETERS';
            app.MOPSOCheckBox = uicheckbox(app.OptimizationAlgorithmPanel);
            app.MOPSOCheckBox.ValueChangedFcn = createCallbackFcn(app, @MOPSOCheckBoxValueChanged, true);
            app.MOPSOCheckBox.Text = 'MOPSO';
            app.NSGAIICheckBox = uicheckbox(app.OptimizationAlgorithmPanel);
            app.NSGAIICheckBox.ValueChangedFcn = createCallbackFcn(app, @NSGAIICheckBoxValueChanged, true);
            app.NSGAIICheckBox.Text = 'NSGA-II';
            app.MultiswarmMOPSOCheckBox = uicheckbox(app.OptimizationAlgorithmPanel);
            app.MultiswarmMOPSOCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiswarmMOPSOCheckBoxValueChanged, true);
            app.MultiswarmMOPSOCheckBox.Text = 'Multiswarm MOPSO';
            app.MultipopulationNSGAIICheckBox = uicheckbox(app.OptimizationAlgorithmPanel);
            app.MultipopulationNSGAIICheckBox.ValueChangedFcn = createCallbackFcn(app, @MultipopulationNSGAIICheckBoxValueChanged, true);
            app.MultipopulationNSGAIICheckBox.Text = 'Multipopulation NSGA-II';
            app.NumberofOptimizationSpinnerLabel = uilabel(app.OptimizationAlgorithmPanel);
            app.NumberofOptimizationSpinnerLabel.Text = 'Number of Optimization';
            app.NumberofOptimizationSpinner = uispinner(app.OptimizationAlgorithmPanel);
            app.NumberofOptimizationSpinner.Limits = [1 Inf];
            app.NumberofOptimizationSpinner.Tooltip = {'Define how many optimization process is to be done'};
            app.NumberofOptimizationSpinner.Value = 1;
            app.Label = uilabel(app.OptimizationAlgorithmPanel);
            app.Label.Text = '';
            app.SaveFolder = uieditfield(app.OptimizationAlgorithmPanel, 'text');
            app.SaveFolder.Tooltip = {'Change the folder name if user wants to save optimization result in specific folder name'};
            app.SelectOptimizationFolder = uibutton(app.OptimizationAlgorithmPanel, 'push');
            app.SelectOptimizationFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectOptimizationFolderPushed, true);
            app.SelectOptimizationFolder.Tooltip = {'This button defines the full path for saving optimization results using dialog box'};
            app.SelectOptimizationFolder.Text = 'Select Optimization Folder';
            app.ResetPathButton = uibutton(app.OptimizationAlgorithmPanel, 'push');
            app.ResetPathButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPathButtonPushed, true);
            app.ResetPathButton.Tooltip = {'This button resets the folder name into default save folder path.'};
            app.ResetPathButton.Text = 'Reset Path';
            app.SaveOptimizationResultsCheckBox = uicheckbox(app.CalculationandPlotSettingsPanel);
            app.SaveOptimizationResultsCheckBox.Tooltip = {'This checkbox enables saving optimization data in selected folder to prevent being overwritten by the next optimization'};
            app.SaveOptimizationResultsCheckBox.Text = 'Save vehicles separately';
            app.TUM_logo = uiimage(app.GUIFahrzeugkonzepteUIFigure);
            app.TUM_logo.ImageSource = 'tum_logo.png';
            app.Output_Text = uilabel(app.GUIFahrzeugkonzepteUIFigure);
            app.Output_Text.Text = '';
            app.AdrianKnigFTMTUMDissertationProject20182022Label = uilabel(app.GUIFahrzeugkonzepteUIFigure);
            app.AdrianKnigFTMTUMDissertationProject20182022Label.Text = '©Adrian König - FTM - TUM - Dissertation Project 2018-2022';
            app.SAVESETTINGSFOREXTERNALSIMULATIONButton = uibutton(app.GUIFahrzeugkonzepteUIFigure, 'push');
            app.SAVESETTINGSFOREXTERNALSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @SAVESETTINGSFOREXTERNALSIMULATIONButtonPushed, true);
            app.SAVESETTINGSFOREXTERNALSIMULATIONButton.Text = 'SAVE SETTINGS FOR EXTERNAL SIMULATION';
            app.Run_Text = uilabel(app.GUIFahrzeugkonzepteUIFigure);
            app.Run_Text.Visible = 'off';
            app.Run_Text.Text = 'Simulation running...please wait';
            app.Lamp = uilamp(app.GUIFahrzeugkonzepteUIFigure);
            % Show the figure after all components are created
            app.GUIFahrzeugkonzepteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_AuVeCoDe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIFahrzeugkonzepteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIFahrzeugkonzepteUIFigure)
        end
    end
end