classdef Vehicle_Showcase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PleaseselectVehiclePanel  matlab.ui.container.Panel
        GreenButton               matlab.ui.control.StateButton
        BlueButton                matlab.ui.control.StateButton
        RedButton                 matlab.ui.control.StateButton
        SingleButton              matlab.ui.control.StateButton
        OptionsPanel              matlab.ui.container.Panel
        addSightButton            matlab.ui.control.Button
        addSkinButton             matlab.ui.control.Button
        VehicleShowroomLabel      matlab.ui.control.Label
        HorizontalSlider          matlab.ui.control.Slider
        HorizontalSliderLabel     matlab.ui.control.Label
        VerticalSlider            matlab.ui.control.Slider
        VerticalSliderLabel       matlab.ui.control.Label
        VAngleEditField           matlab.ui.control.NumericEditField
        VAngleEditFieldLabel      matlab.ui.control.Label
        HAngleEditField           matlab.ui.control.NumericEditField
        HAngleEditFieldLabel      matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Car_Color % RGB Code for Car variant Color
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SingleButton
        function Single_vehicle(app, event)
            app.BlueButton.Value=0;
            app.GreenButton.Value=0;
            app.RedButton.Value=0;
            app.Car_Color=[0.90,0.90,0.90];
            InputPath=fileparts(which("Run_AuVeCoDe.m"));
            load(fullfile(InputPath,'06_Results','Parameters_SingleVehicle.mat'));
            load(fullfile(InputPath,'06_Results','single_vehicle_result.mat'));
            clf(app.UIAxes);
            DISPLAY_vehicle(vehicle,Parameters,app.UIAxes); %displaying vehicle
            close (gcf)
            app.HorizontalSlider.Value=-40;
            app.VerticalSlider.Value=20;
            app.HAngleEditField.Value=-40;
            app.VAngleEditField.Value=20;
        end

        % Value changed function: HAngleEditField, HorizontalSlider
        function Hoizontalchanged(app, event)
            if app.UIAxes.View(1)~=app.HAngleEditField.Value
                app.UIAxes.View(1)=app.HAngleEditField.Value;
                app.HorizontalSlider.Value=app.HAngleEditField.Value;
            elseif app.UIAxes.View(1)~=app.HorizontalSlider.Value
                app.UIAxes.View(1)=app.HorizontalSlider.Value;
                app.HAngleEditField.Value=app.HorizontalSlider.Value;
            end
        end

        % Value changed function: VAngleEditField, VerticalSlider
        function Verticalchanged(app, event)
            if app.UIAxes.View(2)~=app.VAngleEditField.Value
                app.UIAxes.View(2)=app.VAngleEditField.Value;
                app.VerticalSlider.Value=app.VAngleEditField.Value;
            elseif app.UIAxes.View(2)~=app.VerticalSlider.Value
                app.UIAxes.View(2)=app.VerticalSlider.Value;
                app.VAngleEditField.Value=app.VerticalSlider.Value;
            end
        end

        % Button pushed function: addSkinButton
        function addskin(app, event)
            hold (app.UIAxes);
            InputPath=fileparts(which("Run_AuVeCoDe.m"));
            load(fullfile(InputPath,'06_Results','single_vehicle_result.mat'));
             patch(app.UIAxes,vehicle.body.p_grill_f(:,1),vehicle.body.p_grill_f(:,2),vehicle.body.p_grill_f(:,3),app.Car_Color);
             patch(app.UIAxes,vehicle.body.p_grill_r(:,1),vehicle.body.p_grill_r(:,2),vehicle.body.p_grill_r(:,3),app.Car_Color);
             patch(app.UIAxes,vehicle.body.p_hood_f(:,1),vehicle.body.p_hood_f(:,2),vehicle.body.p_hood_f(:,3),app.Car_Color);
             patch(app.UIAxes,vehicle.body.p_hood_r(:,1),vehicle.body.p_hood_r(:,2),vehicle.body.p_hood_r(:,3),app.Car_Color);
             patch(app.UIAxes,vehicle.body.p_pane_f(:,1),vehicle.body.p_pane_f(:,2),vehicle.body.p_pane_f(:,3),'k','FaceAlpha',0.5);
             patch(app.UIAxes,vehicle.body.p_pane_r(:,1),vehicle.body.p_pane_r(:,2),vehicle.body.p_pane_r(:,3),'k','FaceAlpha',0.5);
             patch(app.UIAxes,[vehicle.body.p_pane_f(1:2,1);vehicle.body.p_pane_r(2:-1:1,1)],[vehicle.body.p_pane_f(1:2,2);vehicle.body.p_pane_r(2:-1:1,2)],[vehicle.body.p_pane_f(1:2,3);vehicle.body.p_pane_r(2:-1:1,3)],'k','FaceAlpha',0.5);
             patch(app.UIAxes,[vehicle.body.p_pane_f(3:4,1);vehicle.body.p_pane_r(4:-1:3,1)],[vehicle.body.p_pane_f(3:4,2);vehicle.body.p_pane_r(4:-1:3,2)],[vehicle.body.p_pane_f(3:4,3);vehicle.body.p_pane_r(4:-1:3,3)],'k','FaceAlpha',0.5);
             patch(app.UIAxes,[vehicle.body.p_pane_f(2:3,1);vehicle.body.p_pane_r(3:-1:2,1)],[vehicle.body.p_pane_f(2:3,2);vehicle.body.p_pane_r(3:-1:2,2)],[vehicle.body.p_pane_f(2:3,3);vehicle.body.p_pane_r(3:-1:2,3)],app.Car_Color);
             patch(app.UIAxes,[vehicle.body.p_hood_f(1:2,1);vehicle.body.p_hood_r(2:-1:1,1)],[vehicle.body.p_hood_f(1:2,2);vehicle.body.p_hood_r(2:-1:1,2)],[vehicle.body.p_hood_f(1:2,3);vehicle.body.p_hood_r(2:-1:1,3)],app.Car_Color);
             patch(app.UIAxes,[vehicle.body.p_hood_f(3:4,1);vehicle.body.p_hood_r(4:-1:3,1)],[vehicle.body.p_hood_f(3:4,2);vehicle.body.p_hood_r(4:-1:3,2)],[vehicle.body.p_hood_f(3:4,3);vehicle.body.p_hood_r(4:-1:3,3)],app.Car_Color);
             patch(app.UIAxes,[vehicle.body.p_grill_f(1:2,1);vehicle.body.p_grill_r(2:-1:1,1)],[vehicle.body.p_grill_f(1:2,2);vehicle.body.p_grill_r(2:-1:1,2)],[vehicle.body.p_grill_f(1:2,3);vehicle.body.p_grill_r(2:-1:1,3)],app.Car_Color);
             patch(app.UIAxes,[vehicle.body.p_grill_f(3:4,1);vehicle.body.p_grill_r(4:-1:3,1)],[vehicle.body.p_grill_f(3:4,2);vehicle.body.p_grill_r(4:-1:3,2)],[vehicle.body.p_grill_f(3:4,3);vehicle.body.p_grill_r(4:-1:3,3)],app.Car_Color);

        end

        % Value changed function: RedButton
        function Red_vehicle(app, event)
            app.BlueButton.Value=0;
            app.GreenButton.Value=0;
            app.SingleButton.Value=0;
            app.Car_Color=[1.00,0.41,0.16];  
            InputPath=fileparts(which("Run_AuVeCoDe.m"));
            load(fullfile(InputPath,'06_Results','Parameters_SingleVehicle.mat'));      
            load(fullfile(InputPath,'06_Results','vehicle_result_red.mat'));
            clf(app.UIAxes);
            DISPLAY_vehicle(vehicle_red,Parameters,app.UIAxes); %displaying vehicle
            close (gcf)
            app.HorizontalSlider.Value=-40;
            app.VerticalSlider.Value=20;
            app.HAngleEditField.Value=-40;
            app.VAngleEditField.Value=20;
        end

        % Value changed function: BlueButton
        function Blue_vehicle(app, event)
            app.RedButton.Value=0;
            app.GreenButton.Value=0;
            app.SingleButton.Value=0;
            app.Car_Color=[0.07,0.62,1.00]     ;      
            InputPath=fileparts(which("Run_AuVeCoDe.m"));
            load(fullfile(InputPath,'06_Results','Parameters_SingleVehicle.mat'));      
            load(fullfile(InputPath,'06_Results','vehicle_result_blue.mat'));
            clf(app.UIAxes);
            DISPLAY_vehicle(vehicle_blue,Parameters,app.UIAxes); %displaying vehicle
            close (gcf)
            app.HorizontalSlider.Value=-40;
            app.VerticalSlider.Value=20;
            app.HAngleEditField.Value=-40;
            app.VAngleEditField.Value=20;
        end

        % Value changed function: GreenButton
        function Green_Vehicle(app, event)
            app.BlueButton.Value=0;
            app.RedButton.Value=0;
            app.SingleButton.Value=0;
            app.Car_Color=[0.39,0.83,0.07]  ;         
            InputPath=fileparts(which("Run_AuVeCoDe.m"));
            load(fullfile(InputPath,'06_Results','Parameters_SingleVehicle.mat'));      
            load(fullfile(InputPath,'06_Results','vehicle_result_green.mat'));
            clf(app.UIAxes);
            DISPLAY_vehicle(vehicle_green,Parameters,app.UIAxes); %displaying vehicle
            close (gcf)
            app.HorizontalSlider.Value=-40;
            app.VerticalSlider.Value=20;
            app.HAngleEditField.Value=-40;
            app.VAngleEditField.Value=20;
        end

        % Button pushed function: addSightButton
        function addsight(app, event)
            hold (app.UIAxes);
            InputPath=fileparts(which("Run_AuVeCoDe.m"));
            load(fullfile(InputPath,'06_Results','Parameters_SingleVehicle.mat'));
            load(fullfile(InputPath,'06_Results','single_vehicle_result.mat'));
            plot3(app.UIAxes,vehicle.manikin.AS_point(1),vehicle.manikin.AS_point(2),vehicle.manikin.AS_point(3),'*')
            plot3(app.UIAxes,vehicle.manikin.V_points(:,1),vehicle.manikin.V_points(:,2),vehicle.manikin.V_points(:,3),'o')
            plot3(app.UIAxes,vehicle.manikin.SgRP(1),vehicle.manikin.SgRP(2),vehicle.manikin.SgRP(3),'*')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleaseselectVehiclePanel = uipanel(app.UIFigure);
            app.PleaseselectVehiclePanel.Title = 'Please select Vehicle';
            app.GreenButton = uibutton(app.PleaseselectVehiclePanel, 'state');
            app.GreenButton.ValueChangedFcn = createCallbackFcn(app, @Green_Vehicle, true);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uibutton(app.PleaseselectVehiclePanel, 'state');
            app.BlueButton.ValueChangedFcn = createCallbackFcn(app, @Blue_vehicle, true);
            app.BlueButton.Text = 'Blue';
            app.RedButton = uibutton(app.PleaseselectVehiclePanel, 'state');
            app.RedButton.ValueChangedFcn = createCallbackFcn(app, @Red_vehicle, true);
            app.RedButton.Text = 'Red';
            app.SingleButton = uibutton(app.PleaseselectVehiclePanel, 'state');
            app.SingleButton.ValueChangedFcn = createCallbackFcn(app, @Single_vehicle, true);
            app.SingleButton.Text = 'Single';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.addSightButton = uibutton(app.OptionsPanel, 'push');
            app.addSightButton.ButtonPushedFcn = createCallbackFcn(app, @addsight, true);
            app.addSightButton.Text = 'add Sight';
            app.addSkinButton = uibutton(app.OptionsPanel, 'push');
            app.addSkinButton.ButtonPushedFcn = createCallbackFcn(app, @addskin, true);
            app.addSkinButton.Text = 'add Skin';
            app.VehicleShowroomLabel = uilabel(app.UIFigure);
            app.VehicleShowroomLabel.Text = 'Vehicle Showroom';
            app.HorizontalSlider = uislider(app.UIFigure);
            app.HorizontalSlider.Limits = [-180 180];
            app.HorizontalSlider.ValueChangedFcn = createCallbackFcn(app, @Hoizontalchanged, true);
            app.HorizontalSlider.Value = 40;
            app.HorizontalSliderLabel = uilabel(app.UIFigure);
            app.HorizontalSliderLabel.Text = 'Horizontal';
            app.VerticalSlider = uislider(app.UIFigure);
            app.VerticalSlider.Limits = [-90 90];
            app.VerticalSlider.ValueChangedFcn = createCallbackFcn(app, @Verticalchanged, true);
            app.VerticalSlider.Value = 20;
            app.VerticalSliderLabel = uilabel(app.UIFigure);
            app.VerticalSliderLabel.Text = 'Vertical';
            app.VAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.VAngleEditField.Limits = [-90 90];
            app.VAngleEditField.ValueDisplayFormat = '%.1f';
            app.VAngleEditField.ValueChangedFcn = createCallbackFcn(app, @Verticalchanged, true);
            app.VAngleEditField.Value = 20;
            app.VAngleEditFieldLabel = uilabel(app.UIFigure);
            app.VAngleEditFieldLabel.Text = 'V- Angle';
            app.HAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.HAngleEditField.Limits = [-180 180];
            app.HAngleEditField.ValueDisplayFormat = '%.1f';
            app.HAngleEditField.ValueChangedFcn = createCallbackFcn(app, @Hoizontalchanged, true);
            app.HAngleEditField.Value = -40;
            app.HAngleEditFieldLabel = uilabel(app.UIFigure);
            app.HAngleEditFieldLabel.Text = 'H- Angle';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vehicle Package')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vehicle_Showcase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end